classdef NHP_Metadata_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MetadataInputUIFigure           matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        SavePushTool                    matlab.ui.container.toolbar.PushTool
        UploadPushTool                  matlab.ui.container.toolbar.PushTool
        GridLayout                      matlab.ui.container.GridLayout
        BipolarMapPanel                 matlab.ui.container.Panel
        BIP_Map_BEditField              matlab.ui.control.EditField
        BIP_Map_AEditField              matlab.ui.control.EditField
        SaveBipolarButton               matlab.ui.control.Button
        RefreshBipolarButton            matlab.ui.control.Button
        BIP04BEditField                 matlab.ui.control.EditField
        BIP04EditField_2Label           matlab.ui.control.Label
        BIP03BEditField                 matlab.ui.control.EditField
        BIP03EditField_2Label           matlab.ui.control.Label
        BIP02BEditField                 matlab.ui.control.EditField
        BIP02EditField_2Label           matlab.ui.control.Label
        BIP01BEditField                 matlab.ui.control.EditField
        BIP01EditField_2Label           matlab.ui.control.Label
        BIP04AEditField                 matlab.ui.control.EditField
        BIP04EditFieldLabel             matlab.ui.control.Label
        BIP03AEditField                 matlab.ui.control.EditField
        BIP03EditFieldLabel             matlab.ui.control.Label
        BIP02AEditField                 matlab.ui.control.EditField
        BIP02EditFieldLabel             matlab.ui.control.Label
        BIP01AEditField                 matlab.ui.control.EditField
        BIP01EditFieldLabel             matlab.ui.control.Label
        BLabel                          matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        SAGAEnablePanel                 matlab.ui.container.Panel
        ProximalLabel                   matlab.ui.control.Label
        DistalLabel                     matlab.ui.control.Label
        BCheckBox                       matlab.ui.control.CheckBox
        ACheckBox                       matlab.ui.control.CheckBox
        RecordingPanel                  matlab.ui.container.Panel
        SetBlockParametersButton        matlab.ui.control.Button
        DatePicker                      matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        SubjectEditField                matlab.ui.control.EditField
        SubjectEditFieldLabel           matlab.ui.control.Label
        BlockSpinner                    matlab.ui.control.Spinner
        BlockSpinnerLabel               matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        StimTypeDropDown                matlab.ui.control.DropDown
        StimTypeDropDownLabel           matlab.ui.control.Label
        PatternDropDown                 matlab.ui.control.DropDown
        PatternDropDownLabel            matlab.ui.control.Label
        StimDeliveryDropDown            matlab.ui.control.DropDown
        StimDeliveryDropDownLabel       matlab.ui.control.Label
        OperatorEditField               matlab.ui.control.EditField
        OperatorEditFieldLabel          matlab.ui.control.Label
        StimReturnEditField             matlab.ui.control.EditField
        StimReturnEditFieldLabel        matlab.ui.control.Label
        GoalTextArea                    matlab.ui.control.TextArea
        GoalTextAreaLabel               matlab.ui.control.Label
        RecTypeDropDown                 matlab.ui.control.DropDown
        RecTypeDropDownLabel            matlab.ui.control.Label
        ExperimentTab                   matlab.ui.container.Tab
        AnnotationTextArea              matlab.ui.control.TextArea
        AnnotationTextAreaLabel         matlab.ui.control.Label
        MaxReturnAmplitudemAmeasuredEditField  matlab.ui.control.NumericEditField
        MaxReturnAmplitudemAmeasuredEditFieldLabel  matlab.ui.control.Label
        ExemplarLamp                    matlab.ui.control.Lamp
        MovementLamp                    matlab.ui.control.Lamp
        EMGLamp                         matlab.ui.control.Lamp
        ExemplarCheckBox                matlab.ui.control.CheckBox
        MovementCheckBox                matlab.ui.control.CheckBox
        PatchPatternLabel               matlab.ui.control.Label
        EMGCheckBox                     matlab.ui.control.CheckBox
        SweepDropDown                   matlab.ui.control.DropDown
        SweepDropDownLabel              matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        ProjectDropDown                 matlab.ui.control.DropDown
        ProjectDropDownLabel            matlab.ui.control.Label
        StimulationTab                  matlab.ui.container.Tab
        TrainPeriodsEditField           matlab.ui.control.NumericEditField
        TrainPeriodsEditFieldLabel      matlab.ui.control.Label
        PulseWidthmsEditField           matlab.ui.control.NumericEditField
        PulseWidthmsEditFieldLabel      matlab.ui.control.Label
        StimulatorDropDown              matlab.ui.control.DropDown
        StimulatorDropDownLabel         matlab.ui.control.Label
        ShapeDropDown                   matlab.ui.control.DropDown
        ShapeDropDownLabel              matlab.ui.control.Label
        PulseFrequencyHzEditField       matlab.ui.control.NumericEditField
        PulseFrequencyHzEditFieldLabel  matlab.ui.control.Label
        PulsesperburstEditField         matlab.ui.control.NumericEditField
        PulsesperburstEditFieldLabel    matlab.ui.control.Label
        PulseAmplitudemAEditField       matlab.ui.control.NumericEditField
        PulseAmplitudemAEditFieldLabel  matlab.ui.control.Label
        StimDepthmEditField             matlab.ui.control.NumericEditField
        StimDepthmEditFieldLabel        matlab.ui.control.Label
    end


    properties (GetAccess = public, SetAccess = private)
        meta % Metadata values
        controller = TMSi_Client;
        taskport = udpport("byte", "LocalPort", 3033, "EnablePortSharing", true);
        Muscles
        RawFolder  string = "R:\NMLShare\raw_data\primate";
        RootFolder string = "C:\MyRepos\NML-NHP\2TMSi_MATLAB_Interface"; % Folder containing this app
    end

    methods (Access = public)
        function task_data_cb(app, src, ~)
            %TASK_DATA_CB  Callback when line terminator for udp port is detected.

            % TODO: ADD IN SWITCH CASE FOR DIFFERENT EXPECTED TASK DATA
            % MESSAGE STRUCTURES HERE:
        end
    end

    methods (Access = private)

        function param_widget_cb(app, src)
            %PARAM_WIDGET_CB  Callback applied to any parameter widget.
            tags = strsplit(src.Tag, '.');
            value = src.Value;
            if ischar(value)
                value = string(value);
            end
            switch tags{1}
                case 'Parameter'
                    app.meta.assign(tags{2}, value);
                case 'Descriptive'  % This routing was going to do other stuff, ended up not.
                    app.meta.assign(tags{2}, value);
                case 'Muscle_Map'
                    value = {app.BIP_Map_AEditField.Value; ...
                        app.BIP_Map_BEditField.Value};
                    app.meta.assign('Muscle_Map', value);
                otherwise
                    error("Unhandled tag: %s", tags{1});
            end
        end

        function new_block_cb(app)
            %NEW_BLOCK_CB  Callback that happens any time a block changes.
            v = app.meta.getv('Operator');
            if ismissing(v)
                app.param_widget_cb(app.OperatorEditField);
            else
                app.OperatorEditField.Value = v;
            end
            v = app.meta.getv('Return_Site');
            if ismissing(v)
                app.param_widget_cb(app.StimReturnEditField);
            else
                app.StimReturnEditField.Value = v;
            end
            v = app.meta.getv('Stim_Site');
            if ismissing(v)
                if strcmpi(app.StimDeliveryDropDown.Value, "Patch")
                    iPat = find(strcmpi(app.PatternDropDown.Items, app.PatternDropDown.Value), 1, 'first');
                    if numel(iPat) == 1
                        app.PatternDropDown.Value = app.PatternDropDown.Items{min(iPat + 1, numel(app.PatternDropDown.Items))};
                        app.param_widget_cb(app.PatternDropDown);
                    else
                        app.param_widget_cb(app.PatternDropDown);
                    end

                else
                    app.param_widget_cb(app.StimDeliveryDropDown);
                end
            else
                if endsWith(v, ".txt")
                    app.StimDeliveryDropDown.Value = "Patch";
                    if ~ismember(v, app.PatternDropDown.Items)
                        app.PatternDropDown.Items = [app.PatternDropDown.Items, v];
                    end
                    app.PatternDropDown.Value = v;
                    
                else
                    if strcmpi(app.StimDeliveryDropDown.Value,"Patch")
                        iPat = find(strcmpi(app.PatternDropDown.Items, app.PatternDropDown.Value), 1, 'first');
                        if numel(iPat) == 1
                            app.PatternDropDown.Value = app.PatternDropDown.Items{min(iPat + 1, numel(app.PatternDropDown.Items))};
                            app.param_widget_cb(app.PatternDropDown);
                        else
                            app.PatternDropDown.Value = v;
                        end
                    else
                        app.StimDeliveryDropDown.Value = v;
                    end
                end
            end
            v = app.meta.getv('Goal');
            if ismissing(v)
                app.param_widget_cb(app.GoalTextArea);
            else
                app.GoalTextArea.Value = v;
            end
            v = app.meta.getv('Notes');
            if ismissing(v)
                app.param_widget_cb(app.NotesTextArea);
            else
                app.NotesTextArea.Value = v;
            end
            v = app.meta.getv('Annotation');
            if ismissing(v)
                app.param_widget_cb(app.AnnotationTextArea);
            else
                app.AnnotationTextArea.Value = v;
            end
            v = app.meta.getv('Project');
            if ismissing(v)
                app.param_widget_cb(app.ProjectDropDown);
            else
                app.ProjectDropDown.Value = v;
            end
            v = app.meta.getv('Sweep');
            if ismissing(v)
                app.param_widget_cb(app.SweepDropDown);
            else
                if ~ismember(v, app.SweepDropDown.Items)
                    app.SweepDropDown.Items{end+1} = char(v);
                end
                app.SweepDropDown.Value = v;
            end
            v = app.meta.getv('Stimulator');
            if ismissing(v)
                app.param_widget_cb(app.StimulatorDropDown);
            else
                app.StimulatorDropDown.Value = v;
            end
            v = app.meta.getv('Pulse_Type');
            if ismissing(v)
                app.param_widget_cb(app.ShapeDropDown);
            else
                if ~ismember(v, app.ShapeDropDown.Items)
                    app.ShapeDropDown.Items = horzcat(app.ShapeDropDown.Items, v);
                end
                app.ShapeDropDown.Value = v;
            end
            v = app.meta.getv('Pulse_Frequency');
            if ismissing(v)
                app.param_widget_cb(app.PulseFrequencyHzEditField);
            else
                app.PulseFrequencyHzEditField.Value = v;
            end
            v = app.meta.getv('N_Pulses_Per_Burst');
            if ismissing(v)
                app.param_widget_cb(app.PulsesperburstEditField);
            else
                app.PulsesperburstEditField.Value = v;
            end
            v = app.meta.getv('Pulse_Amplitude_mA');
            if ismissing(v)
                app.param_widget_cb(app.PulseAmplitudemAEditField);
            else
                app.PulseAmplitudemAEditField.Value = v;
            end
            v = app.meta.getv('Depth_um');
            if ismissing(v)
                app.param_widget_cb(app.StimDepthmEditField);
            else
                app.StimDepthmEditField.Value = v;
            end
            app.BIP_Map_AEditField.Value = app.meta.M.Muscle_Map(1);
            app.BIP_Map_BEditField.Value = app.meta.M.Muscle_Map(2);
            v = app.meta.getv('Pulse_Width_ms');
            if ismissing(v)
                app.param_widget_cb(app.PulseWidthmsEditField);
            else
                app.PulseWidthmsEditField.Value = v;
            end
            v = app.meta.getv('Max_Return_Amplitude_mA');
            if ismissing(v)
                app.MaxReturnAmplitudemAmeasuredEditField = 0;
                app.param_widget_cb(app.MaxReturnAmplitudemAmeasuredEditField);
            else
                app.MaxReturnAmplitudemAmeasuredEditField.Value = v;
            end
            v = app.meta.getv('Inter_Burst_Period_s');
            if ismissing(v)
                app.param_widget_cb(app.TrainPeriodsEditField);
            else
                app.TrainPeriodsEditField.Value = v;
            end
            v = app.meta.getv('Stim_Type');
            if ismissing(v)
                app.param_widget_cb(app.StimTypeDropDown);
            else
                if ~ismember(v, app.StimTypeDropDown.Items)
                    app.StimTypeDropDown.Items = horzcat(app.StimTypeDropDown.Items, v);
                end
                app.StimTypeDropDown.Value = v;
            end
            
            if isvalid(app.controller)
                if ~isempty(app.controller.client)
                    if isvalid(app.controller.client)
                        % Update name metadata
                        t = app.DatePicker.Value;
                        YYYY = year(t);
                        MM = month(t);
                        DD = day(t);
                        client__set_rec_name_metadata(app.controller.client, app.SubjectEditField.Value, YYYY, MM, DD, app.BlockSpinner.Value);
                    end
                end
                app.controller.SubjectEditField.Value = app.SubjectEditField.Value;
                app.controller.BlockSpinner.Value = app.BlockSpinner.Value;
                app.controller.StartDatePicker.Value = app.DatePicker.Value;
            end
        end

        function setPatternOptions(app)
            files = uigetfile({'*.pat;*.txt', 'Pattern Files'; '*.*', 'Any File Type'}, ...
                'Select Pattern Files', fullfile(app.RootFolder, 'patterns'), 'MultiSelect', 'on');
            set(app.PatternDropDown, 'Items', files, 'Value', files{1});
        end

        function updatePatternOptions(app)
            %UPDATEPATTERNOPTIONS  Updates items of pattern drop down box.
            pattern_folder = fullfile(app.RootFolder, "patterns", app.SweepDropDown.Value);
            F = dir(fullfile(pattern_folder, "*.txt"));
            if isempty(F)
                warning("No patterns found for %s!", app.SweepDropDown.Value);
                app.PatternDropDown.Enable = false;
            else
                set(app.PatternDropDown, 'Items', {F.name}, 'Value', F(1).name);
                app.PatternDropDown.Enable = true;
            end
        end

        function loadBipolarNameMap(app)
            %LOADBIPOLARNAMEMAP  Loads the bipolar names into edit fields
            app.Muscles = struct(...
                'A', io.JSON(fullfile(app.RawFolder, app.meta.subj, app.meta.M.Muscle_Map(1))), ...
                'B', io.JSON(fullfile(app.RawFolder, app.meta.subj, app.meta.M.Muscle_Map(2))));
            app.BIP01AEditField.Value = app.Muscles.A.Muscles.BIP01;
            app.BIP02AEditField.Value = app.Muscles.A.Muscles.BIP02;
            app.BIP03AEditField.Value = app.Muscles.A.Muscles.BIP03;
            app.BIP04AEditField.Value = app.Muscles.A.Muscles.BIP04;
            app.BIP01BEditField.Value = app.Muscles.B.Muscles.BIP01;
            app.BIP02BEditField.Value = app.Muscles.B.Muscles.BIP02;
            app.BIP03BEditField.Value = app.Muscles.B.Muscles.BIP03;
            app.BIP04BEditField.Value = app.Muscles.B.Muscles.BIP04;
        end

        function saveBipolarNameMap(app)
            %SAVEBIPOLARNAMEMAP  Exports JSON with bipolar names
            app.Muscles.A.write();
            app.Muscles.B.write();
        end

        function handleBipolarNameEdit(app, src)
            %HANDLEBIPOLARNAMEEDIT  Callback for when muscle map edit box is changed
            tag = strsplit(src.Tag, '.');
            app.Muscles.(tag{1}).Muscles.(tag{2}) = src.Value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, metaHandlerObj)
            if nargin < 2
                metaHandlerObj = MetaDataHandler();
            end
            app.meta = metaHandlerObj;
            app.DatePicker.Value = app.meta.date;
            app.SubjectEditField.Value = app.meta.subj;
            app.BlockSpinner.Value = app.meta.block;
            app.updatePatternOptions();
            app.ACheckBox.Value = ismember("A", app.meta.array);
            app.BCheckBox.Value = ismember("B", app.meta.array);
            app.loadBipolarNameMap();
            app.new_block_cb();
            app.taskport.configureTerminator("LF");
            app.taskport.configureCallback("terminator", @app.task_data_cb);
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
            app.meta.set_date(app.DatePicker.Value);
        end

        % Clicked callback: SavePushTool
        function SavePushToolClicked(app, event)
            app.meta.save_metadata();
        end

        % Value changed function: BlockSpinner
        function BlockSpinnerValueChanged(app, event)
            %             app.meta.set_current_metadata_values(app.BlockSpinner.Value);
            if (event.Value - event.PreviousValue) == 1
                app.meta.increment_block();
            elseif (event.Value - event.PreviousValue) == -1
                app.meta.decrement_block();
            else
                event.Source.Value = event.PreviousValue;
                return;
            end
            if ~app.meta.merged
                app.NotesTextArea.Value = "";
                app.AnnotationTextArea.Value = "";
                app.meta.assign("Notes", app.NotesTextArea.Value);
                app.meta.assign("Annotation", app.AnnotationTextArea.Value);
            end
            app.new_block_cb();
        end

        % Value changed function: SubjectEditField
        function SubjectEditFieldValueChanged(app, event)
            app.meta.set_subject(app.SubjectEditField.Value);
            app.BlockSpinner.Value = 0;
        end

        % Button pushed function: SetBlockParametersButton
        function SetBlockParametersButtonPushed(app, event)
            app.meta.merge_current_metadata();
        end

        % Value changed function: RecTypeDropDown
        function RecTypeDropDownValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Callback function
        function StimDeliveryEditFieldValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: StimReturnEditField
        function StimReturnEditFieldValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: OperatorEditField
        function OperatorEditFieldValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: ProjectDropDown
        function ProjectDropDownValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: SweepDropDown
        function SweepDropDownValueChanged(app, event)
            app.param_widget_cb(event.Source);
            app.updatePatternOptions();
        end

        % Value changed function: NotesTextArea
        function NotesTextAreaValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: GoalTextArea
        function GoalTextAreaValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: StimulatorDropDown
        function StimulatorDropDownValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: StimDepthmEditField
        function StimDepthmEditFieldValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: PulseAmplitudemAEditField
        function PulseAmplitudemAEditFieldValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: PulsesperburstEditField
        function PulsesperburstEditFieldValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: PulseFrequencyHzEditField
        function PulseFrequencyHzEditFieldValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: AnnotationTextArea
        function AnnotationTextAreaValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: StimDeliveryDropDown
        function StimDeliveryDropDownValueChanged(app, event)
            if strcmpi(app.StimDeliveryDropDown.Value, "Patch")
                app.updatePatternOptions();
                app.PatchPatternLabel.Text = ...
                    sprintf('%s (%4.2f %%)', app.PatternDropDown.Value, ...
                    app.PulseAmplitudemAEditField.Value);
            else
                app.PatternDropDown.Enable = false;
                app.param_widget_cb(event.Source);
                app.PatchPatternLabel.Text = "No Patch Pattern";
            end
        end

        % Value changed function: PatternDropDown
        function PatternDropDownValueChanged(app, event)
            app.param_widget_cb(event.Source);
            app.PatchPatternLabel.Text = sprintf('%s (%4.2f %%)', app.PatternDropDown.Value, ...
                app.PulseAmplitudemAEditField.Value);
        end

        % Button pushed function: RefreshBipolarButton
        function RefreshBipolarButtonPushed(app, event)
            app.loadBipolarNameMap();
        end

        % Value changed function: BIP_Map_AEditField
        function BIP_Map_AEditFieldValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: BIP_Map_BEditField
        function BIP_Map_BEditFieldValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: BIP01AEditField
        function BIP01AEditFieldValueChanged(app, event)
            app.handleBipolarNameEdit(event.Source);
        end

        % Value changed function: BIP02AEditField
        function BIP02AEditFieldValueChanged(app, event)
            app.handleBipolarNameEdit(event.Source);
        end

        % Value changed function: BIP03AEditField
        function BIP03AEditFieldValueChanged(app, event)
            app.handleBipolarNameEdit(event.Source);
        end

        % Value changed function: BIP04AEditField
        function BIP04AEditFieldValueChanged(app, event)
            app.handleBipolarNameEdit(event.Source);
        end

        % Value changed function: BIP01BEditField
        function BIP01BEditFieldValueChanged(app, event)
            app.handleBipolarNameEdit(event.Source);
        end

        % Value changed function: BIP02BEditField
        function BIP02BEditFieldValueChanged(app, event)
            app.handleBipolarNameEdit(event.Source);
        end

        % Value changed function: BIP03BEditField
        function BIP03BEditFieldValueChanged(app, event)
            app.handleBipolarNameEdit(event.Source);
        end

        % Value changed function: BIP04BEditField
        function BIP04BEditFieldValueChanged(app, event)
            app.handleBipolarNameEdit(event.Source);
        end

        % Button pushed function: SaveBipolarButton
        function SaveBipolarButtonPushed(app, event)
            app.saveBipolarNameMap();
        end

        % Value changed function: ExemplarCheckBox
        function ExemplarCheckBoxValueChanged(app, event)
            value = app.ExemplarCheckBox.Value;
            app.ExemplarLamp.Enable = value;
            app.meta.assign('Exemplar', value);
        end

        % Value changed function: EMGCheckBox
        function EMGCheckBoxValueChanged(app, event)
            value = app.EMGCheckBox.Value;
            app.EMGLamp.Enable = value;
            app.meta.assign('EMG', value);
        end

        % Value changed function: MovementCheckBox
        function MovementCheckBoxValueChanged(app, event)
            value = app.MovementCheckBox.Value;
            app.MovementLamp.Enable = value;
            app.meta.assign('Movement', value);
        end

        % Clicked callback: UploadPushTool
        function UploadPushToolClicked(app, event)
            app.setPatternOptions();
        end

        % Value changed function: MaxReturnAmplitudemAmeasuredEditField
        function MaxReturnAmplitudemAmeasuredEditFieldValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: PulseWidthmsEditField
        function PulseWidthmsEditFieldValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Value changed function: TrainPeriodsEditField
        function TrainPeriodsEditFieldValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end

        % Close request function: MetadataInputUIFigure
        function MetadataInputUIFigureCloseRequest(app, event)
            delete(app.controller);
            delete(app);
        end

        % Value changed function: StimTypeDropDown
        function StimTypeDropDownValueChanged(app, event)
            app.param_widget_cb(event.Source);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MetadataInputUIFigure = uifigure('Visible', 'off');
            app.MetadataInputUIFigure.Name = 'Metadata Input';
            app.MetadataInputUIFigure.Icon = 'cmu_tartans_logo.jpg';
            app.MetadataInputUIFigure.CloseRequestFcn = createCallbackFcn(app, @MetadataInputUIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.MetadataInputUIFigure);
            app.SavePushTool = uipushtool(app.Toolbar);
            app.SavePushTool.Tooltip = {'Save metadata spreadsheet.'};
            app.SavePushTool.ClickedCallback = createCallbackFcn(app, @SavePushToolClicked, true);
            app.SavePushTool.Icon = 'baseline_save_black_24dp.png';
            app.UploadPushTool = uipushtool(app.Toolbar);
            app.UploadPushTool.Tooltip = {'Set pattern files list manually.'};
            app.UploadPushTool.ClickedCallback = createCallbackFcn(app, @UploadPushToolClicked, true);
            app.UploadPushTool.Icon = 'baseline_file_upload_black_24dp.png';
            app.GridLayout = uigridlayout(app.MetadataInputUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.RecTypeDropDownLabel = uilabel(app.SetupTab);
            app.RecTypeDropDownLabel.Text = 'Rec Type';
            app.RecTypeDropDown = uidropdown(app.SetupTab);
            app.RecTypeDropDown.Items = {'pl2', 'poly5', 'rhx', 'other'};
            app.RecTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RecTypeDropDownValueChanged, true);
            app.RecTypeDropDown.Tag = 'Parameter.Type';
            app.RecTypeDropDown.Value = 'poly5';
            app.GoalTextAreaLabel = uilabel(app.SetupTab);
            app.GoalTextAreaLabel.Tag = 'Descriptive.Goal';
            app.GoalTextAreaLabel.Text = 'Goal';
            app.GoalTextArea = uitextarea(app.SetupTab);
            app.GoalTextArea.ValueChangedFcn = createCallbackFcn(app, @GoalTextAreaValueChanged, true);
            app.GoalTextArea.Tag = 'Descriptive.Goal';
            app.GoalTextArea.Placeholder = 'Generic goal of this set of experiments.';
            app.StimReturnEditFieldLabel = uilabel(app.SetupTab);
            app.StimReturnEditFieldLabel.Text = 'Stim Return';
            app.StimReturnEditField = uieditfield(app.SetupTab, 'text');
            app.StimReturnEditField.ValueChangedFcn = createCallbackFcn(app, @StimReturnEditFieldValueChanged, true);
            app.StimReturnEditField.Tag = 'Parameter.Return_Site';
            app.StimReturnEditField.Value = 'Distant';
            app.OperatorEditFieldLabel = uilabel(app.SetupTab);
            app.OperatorEditFieldLabel.Text = 'Operator';
            app.OperatorEditField = uieditfield(app.SetupTab, 'text');
            app.OperatorEditField.ValueChangedFcn = createCallbackFcn(app, @OperatorEditFieldValueChanged, true);
            app.OperatorEditField.Tag = 'Parameter.Operator';
            app.OperatorEditField.Placeholder = 'INITIALS';
            app.OperatorEditField.Value = 'MM';
            app.StimDeliveryDropDownLabel = uilabel(app.SetupTab);
            app.StimDeliveryDropDownLabel.Text = 'Stim Delivery';
            app.StimDeliveryDropDown = uidropdown(app.SetupTab);
            app.StimDeliveryDropDown.Items = {'EMAG', 'SProbe', 'cCDM', 'rCDM', 'TMS', 'Cooner', 'TENS', 'Patch'};
            app.StimDeliveryDropDown.ValueChangedFcn = createCallbackFcn(app, @StimDeliveryDropDownValueChanged, true);
            app.StimDeliveryDropDown.Tag = 'Parameter.Stim_Site';
            app.StimDeliveryDropDown.Value = 'SProbe';
            app.PatternDropDownLabel = uilabel(app.SetupTab);
            app.PatternDropDownLabel.Text = 'Pattern';
            app.PatternDropDown = uidropdown(app.SetupTab);
            app.PatternDropDown.Items = {'None'};
            app.PatternDropDown.ValueChangedFcn = createCallbackFcn(app, @PatternDropDownValueChanged, true);
            app.PatternDropDown.Tag = 'Parameter.Stim_Site';
            app.PatternDropDown.Value = 'None';
            app.StimTypeDropDownLabel = uilabel(app.SetupTab);
            app.StimTypeDropDownLabel.Text = 'Stim Type';
            app.StimTypeDropDown = uidropdown(app.SetupTab);
            app.StimTypeDropDown.Items = {'Electrical', 'Magnetic'};
            app.StimTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StimTypeDropDownValueChanged, true);
            app.StimTypeDropDown.Tag = 'Parameter.Stim_Type';
            app.StimTypeDropDown.Value = 'Electrical';
            app.ExperimentTab = uitab(app.TabGroup);
            app.ExperimentTab.Title = 'Experiment';
            app.ProjectDropDownLabel = uilabel(app.ExperimentTab);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.ExperimentTab);
            app.ProjectDropDown.Items = {'N3_MeNT', 'N3_Patch', 'N3', 'Thalamocortical', 'Other'};
            app.ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectDropDownValueChanged, true);
            app.ProjectDropDown.Tag = 'Parameter.Project';
            app.ProjectDropDown.Value = 'N3_MeNT';
            app.NotesTextAreaLabel = uilabel(app.ExperimentTab);
            app.NotesTextAreaLabel.Tag = 'Descriptive.Goal';
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.ExperimentTab);
            app.NotesTextArea.ValueChangedFcn = createCallbackFcn(app, @NotesTextAreaValueChanged, true);
            app.NotesTextArea.Tag = 'Descriptive.Notes';
            app.NotesTextArea.Placeholder = 'Generic notes about this block.';
            app.SweepDropDownLabel = uilabel(app.ExperimentTab);
            app.SweepDropDownLabel.Text = 'Sweep';
            app.SweepDropDown = uidropdown(app.ExperimentTab);
            app.SweepDropDown.Items = {'HP_10', 'HP_20', 'HP_20_mod', 'ICMS_0', 'ICMS_1', 'ICMS_2', 'ICMS_3', 'ICMS_4', 'ICMS_5', 'EMAG_0', 'EMAG_1', 'EMAG_2', 'EMAG_3', 'EMAG_4', 'EMAG_5', 'CDM_0', 'CDM_1', 'CDM_2', 'CDM_3', 'CDM_4', 'CDM_5', 'Patch_0', 'Patch_1', 'Patch_2', 'Patch_3', 'Patch_4', 'Patch_5', 'J5_0', 'J5_1', 'J5_2', 'J10_0', 'J10_1', 'J10_2', 'J20_0', 'J20_1', 'J20_2', 'J20_3', 'J20_4', ''};
            app.SweepDropDown.ValueChangedFcn = createCallbackFcn(app, @SweepDropDownValueChanged, true);
            app.SweepDropDown.Tag = 'Parameter.Sweep';
            app.SweepDropDown.Value = 'ICMS_0';
            app.EMGCheckBox = uicheckbox(app.ExperimentTab);
            app.EMGCheckBox.ValueChangedFcn = createCallbackFcn(app, @EMGCheckBoxValueChanged, true);
            app.EMGCheckBox.Text = 'EMG';
            app.PatchPatternLabel = uilabel(app.ExperimentTab);
            app.PatchPatternLabel.Text = 'No Patch Pattern';
            app.MovementCheckBox = uicheckbox(app.ExperimentTab);
            app.MovementCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovementCheckBoxValueChanged, true);
            app.MovementCheckBox.Text = 'Movement';
            app.ExemplarCheckBox = uicheckbox(app.ExperimentTab);
            app.ExemplarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExemplarCheckBoxValueChanged, true);
            app.ExemplarCheckBox.Text = 'Exemplar';
            app.EMGLamp = uilamp(app.ExperimentTab);
            app.EMGLamp.Enable = 'off';
            app.MovementLamp = uilamp(app.ExperimentTab);
            app.MovementLamp.Enable = 'off';
            app.ExemplarLamp = uilamp(app.ExperimentTab);
            app.ExemplarLamp.Enable = 'off';
            app.MaxReturnAmplitudemAmeasuredEditFieldLabel = uilabel(app.ExperimentTab);
            app.MaxReturnAmplitudemAmeasuredEditFieldLabel.Text = 'Max Return Amplitude (mA; measured)';
            app.MaxReturnAmplitudemAmeasuredEditField = uieditfield(app.ExperimentTab, 'numeric');
            app.MaxReturnAmplitudemAmeasuredEditField.ValueChangedFcn = createCallbackFcn(app, @MaxReturnAmplitudemAmeasuredEditFieldValueChanged, true);
            app.MaxReturnAmplitudemAmeasuredEditField.Tag = 'Parameter.Max_Return_Amplitude_mA';
            app.AnnotationTextAreaLabel = uilabel(app.ExperimentTab);
            app.AnnotationTextAreaLabel.Tag = 'Descriptive.Goal';
            app.AnnotationTextAreaLabel.Text = 'Annotation';
            app.AnnotationTextArea = uitextarea(app.ExperimentTab);
            app.AnnotationTextArea.ValueChangedFcn = createCallbackFcn(app, @AnnotationTextAreaValueChanged, true);
            app.AnnotationTextArea.Tag = 'Descriptive.Annotation';
            app.AnnotationTextArea.Placeholder = 'Comments about evoked response.';
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            app.StimDepthmEditFieldLabel = uilabel(app.StimulationTab);
            app.StimDepthmEditFieldLabel.Text = 'Stim Depth (μm)';
            app.StimDepthmEditField = uieditfield(app.StimulationTab, 'numeric');
            app.StimDepthmEditField.Limits = [0 Inf];
            app.StimDepthmEditField.ValueChangedFcn = createCallbackFcn(app, @StimDepthmEditFieldValueChanged, true);
            app.StimDepthmEditField.Tag = 'Parameter.Depth_um';
            app.StimDepthmEditField.Value = 6600;
            app.PulseAmplitudemAEditFieldLabel = uilabel(app.StimulationTab);
            app.PulseAmplitudemAEditFieldLabel.Text = 'Pulse Amplitude (mA)';
            app.PulseAmplitudemAEditField = uieditfield(app.StimulationTab, 'numeric');
            app.PulseAmplitudemAEditField.Limits = [0 Inf];
            app.PulseAmplitudemAEditField.ValueChangedFcn = createCallbackFcn(app, @PulseAmplitudemAEditFieldValueChanged, true);
            app.PulseAmplitudemAEditField.Tag = 'Parameter.Pulse_Amplitude_mA';
            app.PulseAmplitudemAEditField.Value = 10;
            app.PulsesperburstEditFieldLabel = uilabel(app.StimulationTab);
            app.PulsesperburstEditFieldLabel.Text = '# Pulses (per burst)';
            app.PulsesperburstEditField = uieditfield(app.StimulationTab, 'numeric');
            app.PulsesperburstEditField.Limits = [0 Inf];
            app.PulsesperburstEditField.ValueChangedFcn = createCallbackFcn(app, @PulsesperburstEditFieldValueChanged, true);
            app.PulsesperburstEditField.Tag = 'Parameter.N_Pulses_Per_Burst';
            app.PulsesperburstEditField.Value = 2;
            app.PulseFrequencyHzEditFieldLabel = uilabel(app.StimulationTab);
            app.PulseFrequencyHzEditFieldLabel.Text = 'Pulse Frequency (Hz)';
            app.PulseFrequencyHzEditField = uieditfield(app.StimulationTab, 'numeric');
            app.PulseFrequencyHzEditField.Limits = [0 Inf];
            app.PulseFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @PulseFrequencyHzEditFieldValueChanged, true);
            app.PulseFrequencyHzEditField.Tag = 'Parameter.Pulse_Frequency';
            app.PulseFrequencyHzEditField.Value = 333;
            app.ShapeDropDownLabel = uilabel(app.StimulationTab);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.StimulationTab);
            app.ShapeDropDown.Items = {'Cathodal', 'Anodal', 'Biphasic_Cathodal', 'Biphasic_Anodal', 'Sinusoid', 'Ramp'};
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Tag = 'Parameter.Pulse_Type';
            app.ShapeDropDown.Value = 'Biphasic_Cathodal';
            app.StimulatorDropDownLabel = uilabel(app.StimulationTab);
            app.StimulatorDropDownLabel.Text = 'Stimulator';
            app.StimulatorDropDown = uidropdown(app.StimulationTab);
            app.StimulatorDropDown.Items = {'AM2100', 'AM4100', 'Behringer', 'Ripple', 'rCDM', 'cCDM', 'cCDMv2'};
            app.StimulatorDropDown.ValueChangedFcn = createCallbackFcn(app, @StimulatorDropDownValueChanged, true);
            app.StimulatorDropDown.Tag = 'Parameter.Stimulator';
            app.StimulatorDropDown.Value = 'AM4100';
            app.PulseWidthmsEditFieldLabel = uilabel(app.StimulationTab);
            app.PulseWidthmsEditFieldLabel.Text = 'Pulse Width (ms)';
            app.PulseWidthmsEditField = uieditfield(app.StimulationTab, 'numeric');
            app.PulseWidthmsEditField.ValueChangedFcn = createCallbackFcn(app, @PulseWidthmsEditFieldValueChanged, true);
            app.PulseWidthmsEditField.Tag = 'Parameter.Pulse_Width_ms';
            app.PulseWidthmsEditField.Value = 0.25;
            app.TrainPeriodsEditFieldLabel = uilabel(app.StimulationTab);
            app.TrainPeriodsEditFieldLabel.Text = 'Train Period (s)';
            app.TrainPeriodsEditField = uieditfield(app.StimulationTab, 'numeric');
            app.TrainPeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @TrainPeriodsEditFieldValueChanged, true);
            app.TrainPeriodsEditField.Tag = 'Parameter.Inter_Burst_Period_s';
            app.TrainPeriodsEditField.Value = 1;
            app.RecordingPanel = uipanel(app.GridLayout);
            app.RecordingPanel.Title = 'Recording';
            app.BlockSpinnerLabel = uilabel(app.RecordingPanel);
            app.BlockSpinnerLabel.Text = 'Block';
            app.BlockSpinner = uispinner(app.RecordingPanel);
            app.BlockSpinner.RoundFractionalValues = 'on';
            app.BlockSpinner.ValueChangedFcn = createCallbackFcn(app, @BlockSpinnerValueChanged, true);
            app.BlockSpinner.Tag = 'Parameter.Index';
            app.BlockSpinner.Editable = 'off';
            app.SubjectEditFieldLabel = uilabel(app.RecordingPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.RecordingPanel, 'text');
            app.SubjectEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectEditFieldValueChanged, true);
            app.SubjectEditField.Value = 'Screamy';
            app.DateDatePickerLabel = uilabel(app.RecordingPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.DatePicker = uidatepicker(app.RecordingPanel);
            app.DatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker.Value = datetime([2022 5 12]);
            app.SetBlockParametersButton = uibutton(app.RecordingPanel, 'push');
            app.SetBlockParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetBlockParametersButtonPushed, true);
            app.SetBlockParametersButton.Text = 'Set Block Parameters';
            app.SAGAEnablePanel = uipanel(app.GridLayout);
            app.SAGAEnablePanel.Title = 'SAGA Enable';
            app.ACheckBox = uicheckbox(app.SAGAEnablePanel);
            app.ACheckBox.Tooltip = {'Enable SAGA A (distal).'};
            app.ACheckBox.Text = 'A';
            app.ACheckBox.Value = true;
            app.BCheckBox = uicheckbox(app.SAGAEnablePanel);
            app.BCheckBox.Tooltip = {'Enable SAGA B (Proximal).'};
            app.BCheckBox.Text = 'B';
            app.BCheckBox.Value = true;
            app.DistalLabel = uilabel(app.SAGAEnablePanel);
            app.DistalLabel.Text = '(Distal)';
            app.ProximalLabel = uilabel(app.SAGAEnablePanel);
            app.ProximalLabel.Text = '(Proximal)';
            app.BipolarMapPanel = uipanel(app.GridLayout);
            app.BipolarMapPanel.Title = 'Bipolar Map';
            app.ALabel = uilabel(app.BipolarMapPanel);
            app.ALabel.Text = '(A)';
            app.BLabel = uilabel(app.BipolarMapPanel);
            app.BLabel.Text = '(B)';
            app.BIP01EditFieldLabel = uilabel(app.BipolarMapPanel);
            app.BIP01EditFieldLabel.Text = 'BIP-01';
            app.BIP01AEditField = uieditfield(app.BipolarMapPanel, 'text');
            app.BIP01AEditField.ValueChangedFcn = createCallbackFcn(app, @BIP01AEditFieldValueChanged, true);
            app.BIP01AEditField.Tag = 'A.BIP01';
            app.BIP02EditFieldLabel = uilabel(app.BipolarMapPanel);
            app.BIP02EditFieldLabel.Text = 'BIP-02';
            app.BIP02AEditField = uieditfield(app.BipolarMapPanel, 'text');
            app.BIP02AEditField.ValueChangedFcn = createCallbackFcn(app, @BIP02AEditFieldValueChanged, true);
            app.BIP02AEditField.Tag = 'A.BIP02';
            app.BIP03EditFieldLabel = uilabel(app.BipolarMapPanel);
            app.BIP03EditFieldLabel.Text = 'BIP-03';
            app.BIP03AEditField = uieditfield(app.BipolarMapPanel, 'text');
            app.BIP03AEditField.ValueChangedFcn = createCallbackFcn(app, @BIP03AEditFieldValueChanged, true);
            app.BIP03AEditField.Tag = 'A.BIP03';
            app.BIP04EditFieldLabel = uilabel(app.BipolarMapPanel);
            app.BIP04EditFieldLabel.Text = 'BIP-04';
            app.BIP04AEditField = uieditfield(app.BipolarMapPanel, 'text');
            app.BIP04AEditField.ValueChangedFcn = createCallbackFcn(app, @BIP04AEditFieldValueChanged, true);
            app.BIP04AEditField.Tag = 'A.BIP04';
            app.BIP01EditField_2Label = uilabel(app.BipolarMapPanel);
            app.BIP01EditField_2Label.Text = 'BIP-01';
            app.BIP01BEditField = uieditfield(app.BipolarMapPanel, 'text');
            app.BIP01BEditField.ValueChangedFcn = createCallbackFcn(app, @BIP01BEditFieldValueChanged, true);
            app.BIP01BEditField.Tag = 'B.BIP01';
            app.BIP02EditField_2Label = uilabel(app.BipolarMapPanel);
            app.BIP02EditField_2Label.Text = 'BIP-02';
            app.BIP02BEditField = uieditfield(app.BipolarMapPanel, 'text');
            app.BIP02BEditField.ValueChangedFcn = createCallbackFcn(app, @BIP02BEditFieldValueChanged, true);
            app.BIP02BEditField.Tag = 'B.BIP02';
            app.BIP03EditField_2Label = uilabel(app.BipolarMapPanel);
            app.BIP03EditField_2Label.Text = 'BIP-03';
            app.BIP03BEditField = uieditfield(app.BipolarMapPanel, 'text');
            app.BIP03BEditField.ValueChangedFcn = createCallbackFcn(app, @BIP03BEditFieldValueChanged, true);
            app.BIP03BEditField.Tag = 'B.BIP03';
            app.BIP04EditField_2Label = uilabel(app.BipolarMapPanel);
            app.BIP04EditField_2Label.Text = 'BIP-04';
            app.BIP04BEditField = uieditfield(app.BipolarMapPanel, 'text');
            app.BIP04BEditField.ValueChangedFcn = createCallbackFcn(app, @BIP04BEditFieldValueChanged, true);
            app.BIP04BEditField.Tag = 'B.BIP04';
            app.RefreshBipolarButton = uibutton(app.BipolarMapPanel, 'push');
            app.RefreshBipolarButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshBipolarButtonPushed, true);
            app.RefreshBipolarButton.Text = 'Refresh';
            app.SaveBipolarButton = uibutton(app.BipolarMapPanel, 'push');
            app.SaveBipolarButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBipolarButtonPushed, true);
            app.SaveBipolarButton.Text = 'Save';
            app.BIP_Map_AEditField = uieditfield(app.BipolarMapPanel, 'text');
            app.BIP_Map_AEditField.ValueChangedFcn = createCallbackFcn(app, @BIP_Map_AEditFieldValueChanged, true);
            app.BIP_Map_AEditField.Tag = 'Muscle_Map.A';
            app.BIP_Map_BEditField = uieditfield(app.BipolarMapPanel, 'text');
            app.BIP_Map_BEditField.ValueChangedFcn = createCallbackFcn(app, @BIP_Map_BEditFieldValueChanged, true);
            app.BIP_Map_BEditField.Tag = 'Muscle_Map.B';
            % Show the figure after all components are created
            app.MetadataInputUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NHP_Metadata_Interface(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MetadataInputUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MetadataInputUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MetadataInputUIFigure)
        end
    end
end