classdef SAGA_Data_Visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAGADataViewerUIFigure          matlab.ui.Figure
        TriggerSyncBitEditField         matlab.ui.control.NumericEditField
        TriggerSyncBitEditFieldLabel    matlab.ui.control.Label
        DataConnectionStatusLamp        matlab.ui.control.Lamp
        DataConnectionStatusLampLabel   matlab.ui.control.Label
        DateTimeLabel                   matlab.ui.control.Label
        SAGALabel                       matlab.ui.control.Label
        ParametersConnectionStatusLamp  matlab.ui.control.Lamp
        ParametersConnectionStatusLampLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        UnipolarStreamTab               matlab.ui.container.Tab
        UnipolarStreamGridLayout        matlab.ui.container.GridLayout
        UnipolarStreamSettingsPanel     matlab.ui.container.Panel
        UnipolarCARCheckBox             matlab.ui.control.CheckBox
        UnipolarCutoff2EditField        matlab.ui.control.NumericEditField
        Cutoff2HzEditFieldLabel         matlab.ui.control.Label
        UnipolarCutoff1EditField        matlab.ui.control.NumericEditField
        Cutoff1HzEditFieldLabel         matlab.ui.control.Label
        UnipolarFilterCheckBox          matlab.ui.control.CheckBox
        UnipolarPerChannelOffsetEditField  matlab.ui.control.NumericEditField
        PerChannelOffsetVEditFieldLabel  matlab.ui.control.Label
        ToggleAllUnipolarChannelsSwitch  matlab.ui.control.ToggleSwitch
        AllChannelsSwitchLabel          matlab.ui.control.Label
        UnipolarStreamChannelsPanel     matlab.ui.container.Panel
        UNI64SwitchLabel                matlab.ui.control.Label
        UNI64Switch                     matlab.ui.control.Switch
        UNI63SwitchLabel                matlab.ui.control.Label
        UNI63Switch                     matlab.ui.control.Switch
        UNI62SwitchLabel                matlab.ui.control.Label
        UNI62Switch                     matlab.ui.control.Switch
        UNI61SwitchLabel                matlab.ui.control.Label
        UNI61Switch                     matlab.ui.control.Switch
        UNI60SwitchLabel                matlab.ui.control.Label
        UNI60Switch                     matlab.ui.control.Switch
        UNI59SwitchLabel                matlab.ui.control.Label
        UNI59Switch                     matlab.ui.control.Switch
        UNI58SwitchLabel                matlab.ui.control.Label
        UNI58Switch                     matlab.ui.control.Switch
        UNI57SwitchLabel                matlab.ui.control.Label
        UNI57Switch                     matlab.ui.control.Switch
        UNI56SwitchLabel                matlab.ui.control.Label
        UNI56Switch                     matlab.ui.control.Switch
        UNI55SwitchLabel                matlab.ui.control.Label
        UNI55Switch                     matlab.ui.control.Switch
        UNI54SwitchLabel                matlab.ui.control.Label
        UNI54Switch                     matlab.ui.control.Switch
        UNI53SwitchLabel                matlab.ui.control.Label
        UNI53Switch                     matlab.ui.control.Switch
        UNI52SwitchLabel                matlab.ui.control.Label
        UNI52Switch                     matlab.ui.control.Switch
        UNI51SwitchLabel                matlab.ui.control.Label
        UNI51Switch                     matlab.ui.control.Switch
        UNI50SwitchLabel                matlab.ui.control.Label
        UNI50Switch                     matlab.ui.control.Switch
        UNI49SwitchLabel                matlab.ui.control.Label
        UNI49Switch                     matlab.ui.control.Switch
        UNI48SwitchLabel                matlab.ui.control.Label
        UNI48Switch                     matlab.ui.control.Switch
        UNI47SwitchLabel                matlab.ui.control.Label
        UNI47Switch                     matlab.ui.control.Switch
        UNI46SwitchLabel                matlab.ui.control.Label
        UNI46Switch                     matlab.ui.control.Switch
        UNI45SwitchLabel                matlab.ui.control.Label
        UNI45Switch                     matlab.ui.control.Switch
        UNI44SwitchLabel                matlab.ui.control.Label
        UNI44Switch                     matlab.ui.control.Switch
        UNI43SwitchLabel                matlab.ui.control.Label
        UNI43Switch                     matlab.ui.control.Switch
        UNI42SwitchLabel                matlab.ui.control.Label
        UNI42Switch                     matlab.ui.control.Switch
        UNI41SwitchLabel                matlab.ui.control.Label
        UNI41Switch                     matlab.ui.control.Switch
        UNI40SwitchLabel                matlab.ui.control.Label
        UNI40Switch                     matlab.ui.control.Switch
        UNI39SwitchLabel                matlab.ui.control.Label
        UNI39Switch                     matlab.ui.control.Switch
        UNI38SwitchLabel                matlab.ui.control.Label
        UNI38Switch                     matlab.ui.control.Switch
        UNI37SwitchLabel                matlab.ui.control.Label
        UNI37Switch                     matlab.ui.control.Switch
        UNI36SwitchLabel                matlab.ui.control.Label
        UNI36Switch                     matlab.ui.control.Switch
        UNI35SwitchLabel                matlab.ui.control.Label
        UNI35Switch                     matlab.ui.control.Switch
        UNI34SwitchLabel                matlab.ui.control.Label
        UNI34Switch                     matlab.ui.control.Switch
        UNI33SwitchLabel                matlab.ui.control.Label
        UNI33Switch                     matlab.ui.control.Switch
        UNI32SwitchLabel                matlab.ui.control.Label
        UNI32Switch                     matlab.ui.control.Switch
        UNI31SwitchLabel                matlab.ui.control.Label
        UNI31Switch                     matlab.ui.control.Switch
        UNI30SwitchLabel                matlab.ui.control.Label
        UNI30Switch                     matlab.ui.control.Switch
        UNI29SwitchLabel                matlab.ui.control.Label
        UNI29Switch                     matlab.ui.control.Switch
        UNI28SwitchLabel                matlab.ui.control.Label
        UNI28Switch                     matlab.ui.control.Switch
        UNI27SwitchLabel                matlab.ui.control.Label
        UNI27Switch                     matlab.ui.control.Switch
        UNI26SwitchLabel                matlab.ui.control.Label
        UNI26Switch                     matlab.ui.control.Switch
        UNI25SwitchLabel                matlab.ui.control.Label
        UNI25Switch                     matlab.ui.control.Switch
        UNI24SwitchLabel                matlab.ui.control.Label
        UNI24Switch                     matlab.ui.control.Switch
        UNI23SwitchLabel                matlab.ui.control.Label
        UNI23Switch                     matlab.ui.control.Switch
        UNI22SwitchLabel                matlab.ui.control.Label
        UNI22Switch                     matlab.ui.control.Switch
        UNI21SwitchLabel                matlab.ui.control.Label
        UNI21Switch                     matlab.ui.control.Switch
        UNI20SwitchLabel                matlab.ui.control.Label
        UNI20Switch                     matlab.ui.control.Switch
        UNI19SwitchLabel                matlab.ui.control.Label
        UNI19Switch                     matlab.ui.control.Switch
        UNI18SwitchLabel                matlab.ui.control.Label
        UNI18Switch                     matlab.ui.control.Switch
        UNI17SwitchLabel                matlab.ui.control.Label
        UNI17Switch                     matlab.ui.control.Switch
        UNI16SwitchLabel                matlab.ui.control.Label
        UNI16Switch                     matlab.ui.control.Switch
        UNI15SwitchLabel                matlab.ui.control.Label
        UNI15Switch                     matlab.ui.control.Switch
        UNI14SwitchLabel                matlab.ui.control.Label
        UNI14Switch                     matlab.ui.control.Switch
        UNI13SwitchLabel                matlab.ui.control.Label
        UNI13Switch                     matlab.ui.control.Switch
        UNI12SwitchLabel                matlab.ui.control.Label
        UNI12Switch                     matlab.ui.control.Switch
        UNI11SwitchLabel                matlab.ui.control.Label
        UNI11Switch                     matlab.ui.control.Switch
        UNI10SwitchLabel                matlab.ui.control.Label
        UNI10Switch                     matlab.ui.control.Switch
        UNI09SwitchLabel                matlab.ui.control.Label
        UNI09Switch                     matlab.ui.control.Switch
        UNI08SwitchLabel                matlab.ui.control.Label
        UNI08Switch                     matlab.ui.control.Switch
        UNI07SwitchLabel                matlab.ui.control.Label
        UNI07Switch                     matlab.ui.control.Switch
        UNI06SwitchLabel                matlab.ui.control.Label
        UNI06Switch                     matlab.ui.control.Switch
        UNI05SwitchLabel                matlab.ui.control.Label
        UNI05Switch                     matlab.ui.control.Switch
        UNI04SwitchLabel                matlab.ui.control.Label
        UNI04Switch                     matlab.ui.control.Switch
        UNI03SwitchLabel                matlab.ui.control.Label
        UNI03Switch                     matlab.ui.control.Switch
        UNI02SwitchLabel                matlab.ui.control.Label
        UNI02Switch                     matlab.ui.control.Switch
        UNI01Switch                     matlab.ui.control.Switch
        UNI01SwitchLabel                matlab.ui.control.Label
        UnipolarUIAxes                  matlab.ui.control.UIAxes
        UnipolarAverageTab              matlab.ui.container.Tab
        UnipolarAverageGridLayout       matlab.ui.container.GridLayout
        UnipolarAverageSettingsPanel    matlab.ui.container.Panel
        UnipolarAverageRectifyCheckBox  matlab.ui.control.CheckBox
        ResetUnipolarAverageButton      matlab.ui.control.Button
        UnipolarAverageNEditField       matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        StopUnipolarAverageSpinner      matlab.ui.control.Spinner
        StopDatamsSpinnerLabel          matlab.ui.control.Label
        StartUnipolarAverageSpinner     matlab.ui.control.Spinner
        StartDatamsSpinnerLabel         matlab.ui.control.Label
        UnipolarAverageCARCheckBox      matlab.ui.control.CheckBox
        UnipolarAverageCutoff2EditField  matlab.ui.control.NumericEditField
        Cutoff2HzEditFieldLabel_2       matlab.ui.control.Label
        UnipolarAverageCutoff1EditField  matlab.ui.control.NumericEditField
        Cutoff1HzEditFieldLabel_2       matlab.ui.control.Label
        UnipolarAverageFilterCheckBox   matlab.ui.control.CheckBox
        UnipolarAverageChannelEditField  matlab.ui.control.NumericEditField
        UNIChannelEditFieldLabel        matlab.ui.control.Label
        UnipolarAverageUIAxes           matlab.ui.control.UIAxes
        UnipolarRasterTab               matlab.ui.container.Tab
        UnipolarRasterGridLayout        matlab.ui.container.GridLayout
        UnipolarRasterSettingsPanel     matlab.ui.container.Panel
        BipolarStreamTab                matlab.ui.container.Tab
        BipolarStreamGridLayout         matlab.ui.container.GridLayout
        BipolarStreamSettingsPanel      matlab.ui.container.Panel
        BipolarCutoff2EditField         matlab.ui.control.NumericEditField
        BipolarCutoff2EditFieldLabel    matlab.ui.control.Label
        BipolarCutoff1EditField         matlab.ui.control.NumericEditField
        BipolarCutoff1EditFieldLabel    matlab.ui.control.Label
        BipolarPerChannelOffsetEditField  matlab.ui.control.NumericEditField
        BipolarPerChannelOffsetVEditFieldLabel  matlab.ui.control.Label
        BipolarFilterCheckBox           matlab.ui.control.CheckBox
        ToggleAllBipolarChannelsSwitch  matlab.ui.control.ToggleSwitch
        ToggleAllBipolarChannelsSwitchLabel  matlab.ui.control.Label
        BipolarStreamChannelsPanel      matlab.ui.container.Panel
        BIP04SwitchLabel                matlab.ui.control.Label
        BIP04Switch                     matlab.ui.control.Switch
        BIP03SwitchLabel                matlab.ui.control.Label
        BIP03Switch                     matlab.ui.control.Switch
        BIP02SwitchLabel                matlab.ui.control.Label
        BIP02Switch                     matlab.ui.control.Switch
        BIP01Switch                     matlab.ui.control.Switch
        BIP01SwitchLabel                matlab.ui.control.Label
        BipolarUIAxes                   matlab.ui.control.UIAxes
        BipolarAverageTab               matlab.ui.container.Tab
        BipolarAverageGridLayout        matlab.ui.container.GridLayout
        BipolarAverageSettingsPanel     matlab.ui.container.Panel
        BipolarAverageRectifyCheckBox   matlab.ui.control.CheckBox
        BipolarAverageNEditField        matlab.ui.control.NumericEditField
        NEditFieldLabel_2               matlab.ui.control.Label
        ResetBipolarAverageButton       matlab.ui.control.Button
        StopBipolarAverageSpinner       matlab.ui.control.Spinner
        StopDatamsSpinner_2Label        matlab.ui.control.Label
        StartBipolarAverageSpinner      matlab.ui.control.Spinner
        StartDatamsSpinner_2Label       matlab.ui.control.Label
        BipolarAverageCutoff2EditField  matlab.ui.control.NumericEditField
        Cutoff2HzEditFieldLabel_3       matlab.ui.control.Label
        BipolarAverageCutoff1EditField  matlab.ui.control.NumericEditField
        Cutoff1HzEditFieldLabel_3       matlab.ui.control.Label
        BipolarAverageChannelEditField  matlab.ui.control.NumericEditField
        BIPChannelLabel                 matlab.ui.control.Label
        BipolarAverageFilterCheckBox    matlab.ui.control.CheckBox
        BipolarAverageUIAxes            matlab.ui.control.UIAxes
        RMSContourTab                   matlab.ui.container.Tab
        RMSContourGridLayout            matlab.ui.container.GridLayout
        RMSContourSettingsPanel         matlab.ui.container.Panel
        ICAStreamTab                    matlab.ui.container.Tab
        ICAStreamGridLayout             matlab.ui.container.GridLayout
        ICAStreamChannelsPanel          matlab.ui.container.Panel
        ToggleAllICAChannelsSwitch      matlab.ui.control.ToggleSwitch
        ToggleAllBipolarChannelsSwitchLabel_2  matlab.ui.control.Label
        ICA20SwitchLabel                matlab.ui.control.Label
        ICA20Switch                     matlab.ui.control.Switch
        ICA19SwitchLabel                matlab.ui.control.Label
        ICA19Switch                     matlab.ui.control.Switch
        ICA18SwitchLabel                matlab.ui.control.Label
        ICA18Switch                     matlab.ui.control.Switch
        ICA17Switch                     matlab.ui.control.Switch
        ICA17SwitchLabel                matlab.ui.control.Label
        ICA16SwitchLabel                matlab.ui.control.Label
        ICA16Switch                     matlab.ui.control.Switch
        ICA15SwitchLabel                matlab.ui.control.Label
        ICA15Switch                     matlab.ui.control.Switch
        ICA14SwitchLabel                matlab.ui.control.Label
        ICA14Switch                     matlab.ui.control.Switch
        ICA13Switch                     matlab.ui.control.Switch
        ICA13SwitchLabel                matlab.ui.control.Label
        ICA12SwitchLabel                matlab.ui.control.Label
        ICA12Switch                     matlab.ui.control.Switch
        ICA11SwitchLabel                matlab.ui.control.Label
        ICA11Switch                     matlab.ui.control.Switch
        ICA10SwitchLabel                matlab.ui.control.Label
        ICA10Switch                     matlab.ui.control.Switch
        ICA09Switch                     matlab.ui.control.Switch
        ICA09SwitchLabel                matlab.ui.control.Label
        ICA08SwitchLabel                matlab.ui.control.Label
        ICA08Switch                     matlab.ui.control.Switch
        ICA07SwitchLabel                matlab.ui.control.Label
        ICA07Switch                     matlab.ui.control.Switch
        ICA06SwitchLabel                matlab.ui.control.Label
        ICA06Switch                     matlab.ui.control.Switch
        ICA05Switch                     matlab.ui.control.Switch
        ICA05SwitchLabel                matlab.ui.control.Label
        ICA04SwitchLabel                matlab.ui.control.Label
        ICA04Switch                     matlab.ui.control.Switch
        ICA03SwitchLabel                matlab.ui.control.Label
        ICA03Switch                     matlab.ui.control.Switch
        ICA02SwitchLabel                matlab.ui.control.Label
        ICA02Switch                     matlab.ui.control.Switch
        ICA01Switch                     matlab.ui.control.Switch
        ICA01SwitchLabel                matlab.ui.control.Label
        ICAUIAxes                       matlab.ui.control.UIAxes
        ICARasterTab                    matlab.ui.container.Tab
        ICARasterGridLayout             matlab.ui.container.GridLayout
        ICARasterSettingsPanel          matlab.ui.container.Panel
    end

    
    properties (Access = public)
        data
    end
    
    properties (Hidden, Access = public)
        CDATA
    end
    
    properties (GetAccess = public, SetAccess = protected)
        Tag         % Either "A" or "B"
        cfg         % Parsed configuration struct
        connected = struct('parameters', false, 'data', false); % True if the parameters server (from controller UI) is connected
        mode = 'US' % Current "mode" based on selected visualizer tab
        plot_fcn    % Callback to use in handling data
        filters     % Filter coefficients and parameters
        H           % Struct containing the different graphic objects to update
        tcp         % TCP server
        udp         % UDP Port that broadcasts the 'Tag' property any time the Tab is changed.
        n = struct
    end
    
    properties (Hidden, Constant)
        ICA_OFFSET = 3.5
    end
    
    methods (Access = public)
        function update_bipolar_stream_data(app, ch, data)
            %UPDATE_BIPOLAR_STREAM_DATA  Update bipolar stream handles
            if ch > 0
                en = app.cfg.SAGA.Channels.en.BIP;
                b = app.filters.BIP.b;
                a = app.filters.BIP.a;
                apply_filter = app.filters.BIP.en;
                if en(ch)
                    if apply_filter
                        [ydata, app.filters.BIP.z(ch,:)] = filter(b,a,(data - mean(data)), app.filters.BIP.z(ch,:));
                        app.H.BIP(ch).YData = ydata + sum(en(1:ch))*app.BipolarPerChannelOffsetEditField.Value;
                    else
                        app.H.BIP(ch).YData = data - mean(data) + (sum(en(1:ch))-1)*app.BipolarPerChannelOffsetEditField.Value;
                    end
                end
            else
                set(app.H.BIP, 'XData', data); 
                xl = [data(1) data(app.n.all_samples)];
                set(app.BipolarUIAxes, 'XLim', xl, 'XTick', [xl(1), data(app.n.mid_sample), xl(2)]);
                drawnow;
            end
        end
        
        function update_unipolar_stream_data(app, ch, data)
            %UPDATE_UNIPOLAR_STREAM_DATA  Update unipolar stream handles
            if ch > 0
                en = app.cfg.SAGA.Channels.en.UNI;
                b = app.filters.UNI.b;
                a = app.filters.UNI.a;
                apply_filter = app.filters.UNI.en;
                if en(ch)
                    if apply_filter
                        [ydata, app.filters.UNI.z(ch,:)] = filter(b,a,(data - mean(data)), app.filters.UNI.z(ch,:));
                        app.H.UNI(ch).YData = ydata + sum(en(1:ch))*app.UnipolarPerChannelOffsetEditField.Value;
                    else
                        app.H.UNI(ch).YData = data - mean(data) + (sum(en(1:ch))-1)*app.UnipolarPerChannelOffsetEditField.Value;
                    end
                end
            else
                set(app.H.UNI, 'XData', data);
                xl = [data(1), data(app.n.all_samples)];
                set(app.UnipolarUIAxes, 'XLim', xl, 'XTick', [xl(1), data(app.n.mid_sample), xl(2)]);
                drawnow;
            end
        end
        
        function update_ica_stream_data(app, ch, data)
            %UPDATE_ICA_STREAM_DATA  Update ICA stream handles
            if ch > 0
                en = app.cfg.SAGA.Channels.en.ICA;
                if en(ch)
                    app.H.ICA(ch).YData = data + sum(en(1:ch))*app.ICA_OFFSET;
                    drawnow;
                end
            else
                set(app.H.ICA, 'XData', data);
                xl = [data(1), data(app.n.all_samples)];
                set(app.ICAUIAxes, 'XLim', xl, 'XTick', [xl(1), data(app.n.mid_sample), xl(2)]);
                drawnow;
            end
        end
        
        function update_unipolar_average_data(app, ch, data)
            %UPDATE_UNIPOLAR_AVERAGE_DATA  Update unipolar average handles
            if ch > 0
                nt = numel(app.H.trigs);
                snippets = nan(nt, numel(app.H.sample_indices));
                if app.filters.UNI_AVG.en
                    data = filter(app.filters.UNI_AVG.b, app.filters.UNI_AVG.a, data);
                end
                if app.filters.UNI_AVG.rectify
                    data = abs(data);
                end
                for ii = 1:nt
                    vec = app.H.sample_indices + app.H.trigs(ii);
                    idx = (vec > 0) & (vec < numel(data));
                    snippets(ii, idx) = data(vec(idx));
                end
                app.shift_in_new_averaging_snippets_(snippets);
            else
                app.parse_sync_trigs_(data);
            end
        end

        function update_bipolar_average_data(app, ch, data)
            %UPDATE_BIPOLAR_AVERAGE_DATA  Update unipolar average handles
            if ch > 0
                nt = numel(app.H.trigs);
                snippets = nan(nt, numel(app.H.sample_indices));
                if app.filters.BIP_AVG.en
                    data = filter(app.filters.BIP_AVG.b, app.filters.BIP_AVG.a, data);
                end
                if app.filters.BIP_AVG.rectify
                    data = abs(data);
                end
                for ii = 1:nt
                    vec = app.H.sample_indices + app.H.trigs(ii);
                    idx = (vec > 0) & (vec < numel(data));
                    snippets(ii, idx) = data(vec(idx));
                end
                app.shift_in_new_averaging_snippets_(snippets);
            else
                app.parse_sync_trigs_(data);
            end
        end
        
        function setConnectionStatus(app, src, evt) %#ok<INUSL> 
            %SETCONNECTIONSTATUS  Indicate whether the app has a client (the controller application) connected over TCP.
            if evt.Connected
                app.ParametersConnectionStatusLamp.Color = [0.39,0.83,0.07];
                app.connected.parameters = true;
            else
                app.ParametersConnectionStatusLamp.Color = [0.65,0.65,0.65];
                app.connected.parameters = false;
            end
            app.setDateTimeLabel(evt.AbsoluteTime);
        end
        
        function setDateTimeLabel(app, dt)
            %SETDATETIMELABEL Set the text of the datetime label at the top of the interface.
            %
            % Inputs:
            %   dt - Datetime (scalar)
            app.DateTimeLabel.Text = sprintf("%4d-%02d-%02d %02d:%02d:%05.3f", ...
                year(dt), month(dt), day(dt), hour(dt), minute(dt), second(dt));
        end
    end
    
    methods (Access = private)
        function disable_bipolar_stream(app, chnum)
            %DISABLE_BIPOLAR_STREAM  Disable the selected channel(s)
            %
            % Syntax:
            %   app.disable_bipolar_stream(chnum)
            %
            % Inputs:
            %   chnum - Scalar or vector of integers indicating 1-indexed
            %               channel-number(s) to disable.
            app.cfg.SAGA.Channels.en.BIP(chnum) = false(size(chnum));
            for ii = 1:numel(chnum)
                h = findobj(app.BipolarStreamChannelsPanel.Children, 'Tag', sprintf('%d', chnum(ii)), 'Type', 'uiswitch');
                app.H.BIP(chnum(ii)).Visible = 'off';
                h.Value = 'Off';
            end
        end
        
        function enable_bipolar_stream(app, chnum)
            %ENABLE_BIPOLAR_STREAM  Enable the selected channel(s)
            %
            % Syntax:
            %   app.enable_bipolar_stream(chnum)
            %
            % Inputs:
            %   chnum - Scalar or vector of integers indicating 1-indexed
            %               channel-number(s) to enable.
            app.cfg.SAGA.Channels.en.BIP(chnum) = true(size(chnum));
            for ii = 1:numel(chnum)
                h = findobj(app.BipolarStreamChannelsPanel.Children, 'Tag', sprintf('%d', chnum(ii)), 'Type', 'uiswitch');
                app.H.BIP(chnum(ii)).Visible = 'on';
                h.Value = 'On';
            end
        end
        
        function disable_unipolar_stream(app, chnum)
            %DISABLE_UNIPOLAR_STREAM  Disable the selected channel(s)
            %
            % Syntax:
            %   app.disable_unipolar_stream(chnum)
            %
            % Inputs:
            %   chnum - Scalar or vector of integers indicating 1-indexed
            %               channel-number(s) to disable.
            app.cfg.SAGA.Channels.en.UNI(chnum) = false(size(chnum));
            for ii = 1:numel(chnum)
                h = findobj(app.UnipolarStreamChannelsPanel.Children, 'Tag', sprintf('%d', chnum(ii)), 'Type', 'uiswitch');
                app.H.UNI(chnum(ii)).Visible = 'off';
                h.Value = 'Off';
            end
        end
        
        function enable_unipolar_stream(app, chnum)
            %ENABLE_UNIPOLAR_STREAM  Enable the selected channel(s)
            %
            % Syntax:
            %   app.enable_unipolar_stream(chnum)
            %
            % Inputs:
            %   chnum - Scalar or vector of integers indicating 1-indexed
            %               channel-number(s) to enable.
            app.cfg.SAGA.Channels.en.UNI(chnum) = true(size(chnum));
            for ii = 1:numel(chnum)
                h = findobj(app.UnipolarStreamChannelsPanel.Children, 'Tag', sprintf('%d', chnum(ii)), 'Type', 'uiswitch');
                app.H.UNI(chnum(ii)).Visible = 'on';
                h.Value = 'On';
            end
        end
        
        function disable_ica_stream(app, chnum)
            %DISABLE_ICA_STREAM  Disable the selected channel(s)
            %
            % Syntax:
            %   app.disable_ica_stream(chnum)
            %
            % Inputs:
            %   chnum - Scalar or vector of integers indicating 1-indexed
            %               channel-number(s) to disable.
            app.cfg.SAGA.Channels.en.ICA(chnum) = false(size(chnum));
            for ii = 1:numel(chnum)
                h = findobj(app.ICAStreamChannelsPanel.Children, 'Tag', sprintf('%d', chnum(ii)), 'Type', 'uiswitch');
                app.H.ICA(chnum(ii)).Visible = 'off';
                h.Value = 'Off';
            end
        end
        
        function enable_ica_stream(app, chnum)
            %ENABLE_ICA_STREAM  Enable the selected channel(s)
            %
            % Syntax:
            %   app.enable_ica_stream(chnum)
            %
            % Inputs:
            %   chnum - Scalar or vector of integers indicating 1-indexed
            %               channel-number(s) to enable.
            app.cfg.SAGA.Channels.en.ICA(chnum) = true(size(chnum));
            for ii = 1:numel(chnum)
                h = findobj(app.ICAStreamChannelsPanel.Children, 'Tag', sprintf('%d', chnum(ii)), 'Type', 'uiswitch');
                app.H.ICA(chnum(ii)).Visible = 'on';
                h.Value = 'On';
            end
        end
        
        function c = init_cdata(app)
            %INIT_CDATA Helper function to initialize the colormap stuff
            my_colormap = cm.map('rosette');
            cmobj = cm.cmap([1 size(my_colormap,1)], my_colormap);
            c = struct( ...
                'UNI', double(cmobj(linspace(1,32,64)))./255.0, ...
                'BIP', double(cmobj(linspace(1,32, 4)))./255.0, ...
                'ICA', double(cmobj(linspace(1,32,app.cfg.Default.N_ICs)))./255.0  ...
                );
            app.CDATA = c;
        end
        
        function init_filters(app)
            %INIT_FILTERS Helper function to initialize filter coefficients
            % Setup filters struct
            % 'b' - numerator coefficients
            % 'a' - denominator coefficients
            % 'z' - filter state (final state values from previous
            %           step; rows are channels columns are values for each
            %           state coefficient which depend on filter order;
            %           since we don't allow order to change (2nd order 
            %           butterworth IIR bandpass filter design, so we 
            %           know this will have 4 columns). For UNI, we add 1
            %           more row in case we are filtering the CAR signal.
            % 'en' - Apply BPF?
            app.filters = struct(...
                'UNI', struct('b', [], 'a', [], 'z', zeros(65,4), 'en', app.UnipolarFilterCheckBox.Value, 'CAR', app.UnipolarCARCheckBox.Value), ...
                'BIP', struct('b', [], 'a', [], 'z', zeros(4,4), 'en', app.BipolarFilterCheckBox.Value), ...
                'UNI_AVG', struct('b', [], 'a', [], 'z', zeros(1,4), 'en', app.UnipolarAverageFilterCheckBox.Value, 'CAR', app.UnipolarAverageCARCheckBox.Value, 'rectify', app.UnipolarAverageRectifyCheckBox.Value), ...
                'BIP_AVG', struct('b', [], 'a', [], 'z', zeros(1,4), 'en', app.BipolarAverageFilterCheckBox.Value, 'rectify', app.BipolarAverageRectifyCheckBox.Value));
            [app.filters.UNI.b,app.filters.UNI.a] =  ...
                butter(2,([app.UnipolarCutoff1EditField.Value, app.UnipolarCutoff2EditField.Value])./(app.cfg.Default.Sample_Rate/2), 'bandpass');
            [app.filters.BIP.b,app.filters.BIP.a] =  ...
                butter(2,([app.BipolarCutoff1EditField.Value, app.BipolarCutoff2EditField.Value])./(app.cfg.Default.Sample_Rate/2), 'bandpass');
            [app.filters.UNI_AVG.b,app.filters.UNI_AVG.a] =  ...
                butter(2,([app.UnipolarAverageCutoff1EditField.Value, app.UnipolarAverageCutoff2EditField.Value])./(app.cfg.Default.Sample_Rate/2), 'bandpass');
            [app.filters.BIP_AVG.b,app.filters.BIP_AVG.a] =  ...
                butter(2,([app.BipolarAverageCutoff1EditField.Value, app.BipolarAverageCutoff2EditField.Value])./(app.cfg.Default.Sample_Rate/2), 'bandpass');
        end
        
        function tf = getEnabledChannels(app, tag)
            if nargin < 2
                tag = app.TabGroup.SelectedTab.Tag;
            end
            switch string(upper(tag))
                case {"US", "UA", "UR"}
                    n_ch = numel(app.cfg.SAGA.Channels.UNI);
                    tf = false(n_ch,1);
                    for ii = 1:n_ch
                        name = sprintf("UNI%02dSwitch", ii);
                        try
                            tf(ii) = app.(name).Value;
                        catch me
                            warning("No matching element for name == '%s'", name);
                            rethrow(me);
                        end
                    end
                case {"BS", "BA"}
                    n_ch = numel(app.cfg.SAGA.Channels.BIP);
                    tf = false(n_ch,1);
                    for ii = 1:n_ch
                        name = sprintf("BIP%02dSwitch", ii);
                        tf(ii) = app.(name).Value;
                    end
                case {"IS", "IR"}
                    n_ch = numel(app.cfg.SAGA.Channels.ICA);
                    tf = false(n_ch,1);
                    for ii = 1:n_ch
                        name = sprintf("ICA%02dSwitch", ii);
                        tf(ii) = app.(name).Value;
                    end                
                case "RC"
                    
                otherwise
                    error("Unrecognized tab Tag value: %s", app.TabGroup.SelectedTab.Tag);
            end
        end

        function send_uni_stream_udp_message(app)
            %SEND_UNI_STREAM_UDP_MESSAGE  Sends message to configure as UDP stream with currently-enabled UNIPOLAR (ARRAY) channel-set.
            ch = char(app.cfg.SAGA.Channels.UNI(app.cfg.SAGA.Channels.en.UNI) + 96);
            msg = char(sprintf('%s.%s.%d.%s', app.mode, app.Tag, double(app.filters.UNI.CAR), ch));
            app.send_message_(msg);
        end

        function send_uni_average_udp_message(app)
            %SEND_UNI_AVERAGE_UDP_MESSAGE  Sends message to configure as UDP averaging with currently-selected array channel.
            msg = char(sprintf('%s.%s.%d.%d', app.mode, app.Tag, double(app.filters.UNI_AVG.CAR), app.cfg.SAGA.Channels.UNI(app.UnipolarAverageChannelEditField.Value)));
            app.send_message_(msg);
        end

        function send_bip_stream_udp_message(app)
            %SEND_BIP_STREAM_UDP_MESSAGE  Sends message to configure as UDP stream with currently-enabled BIPOLAR channel-set.
            ch = char(app.cfg.SAGA.Channels.BIP(app.cfg.SAGA.Channels.en.BIP) + 96);
            msg = char(sprintf('%s.%s.%s', app.mode, app.Tag, ch));
            app.send_message_(msg);
        end

        function send_bip_average_udp_message(app)
            %SEND_BIP_AVERAGE_UDP_MESSAGE  Sends message to configure as UDP averaging with currently-selected bipolar channel.
            msg = char(sprintf('%s.%s.%d', app.mode, app.Tag, app.cfg.SAGA.Channels.BIP(app.BipolarAverageChannelEditField.Value)));
            app.send_message_(msg);
        end

        function send_ica_stream_udp_message(app)
            %SEND_ICA_STREAM_UDP_MESSAGE  Sends message to configure as UDP stream with currently-enabled BIPOLAR channel-set.
            ch = char(app.cfg.SAGA.Channels.ICA(app.cfg.SAGA.Channels.en.ICA) + 96);
            msg = char(sprintf('%s.%s.%s', app.mode, app.Tag, ch));
            app.send_message_(msg);
        end

        function send_mode_only_udp_message(app)
            %SEND_MODE_ONLY_UDP_MESSAGE  Sends message to configure as UDP stream with currently-enabled BIPOLAR channel-set.
            msg = char(sprintf('%s.%s', app.mode, app.Tag));
            app.send_message_(msg);
        end
        
        function send_message_(app, msg)
            fprintf(1,'Sending message: %s\n', msg);
            if app.connected.data
                app.udp.writeline(msg, app.cfg.Server.Address.UDP, app.cfg.Server.UDP.extra);
            end
            if app.connected.parameters
                app.tcp.writeline(msg);
            end
        end
        
        function h = init_uni_stream_tab_graphics(app)
            xvec = (0:(app.cfg.SAGA.Channels.n.samples-1))./app.cfg.Default.Sample_Rate;
            h = struct('UNI', gobjects(numel(app.cfg.SAGA.Channels.UNI), 1));
            for ii = 1:numel(h.UNI)
                offset = app.UnipolarPerChannelOffsetEditField.Value * (ii-1);
                h.UNI(ii) = line(app.UnipolarUIAxes, xvec, zeros(size(xvec)) + offset, ...
                    'Color', app.CDATA.UNI(ii,:), ...
                    'LineWidth', 1.5, ...
                    'DisplayName', sprintf('UNI%02d', ii), ...
                    'Visible', 'off' );
                             
            end
        end

        function h = init_uni_average_tab_graphics(app)
            fs = app.cfg.Default.Sample_Rate;
            sample_indices = round(app.StartUnipolarAverageSpinner.Value * fs * 1e-3):round(app.StopUnipolarAverageSpinner.Value * fs * 1e-3);
            t_vec = sample_indices / fs * 1e3;
            h = struct('ax', app.UnipolarAverageUIAxes, 'mean', gobjects(1,1), 'sd', gobjects(1,1), 'history', nan(app.UnipolarAverageNEditField.Value, numel(sample_indices)), 'n', 0, 'n_max', app.UnipolarAverageNEditField.Value, 'sample_indices', sample_indices, 'trigs', []);
            npt= numel(sample_indices);
            faces = [1:2*npt, 1];
            verts = [[t_vec'; flipud(t_vec')], [ones(npt,1); -1.*ones(npt,1)]];
            app.UnipolarAverageUIAxes.Title.String = "(N = 0)";
            h.sd = patch(app.UnipolarAverageUIAxes, 'Faces', faces, 'Vertices', verts, 'FaceColor', [0.65 0.65 0.65], 'DisplayName', '\pm1SD','EdgeColor', 'none');
            h.mean = line(app.UnipolarAverageUIAxes, t_vec, zeros(size(t_vec)), 'Color', 'k', 'LineWidth', 2.5, 'DisplayName', 'Mean');
            
            if app.UnipolarAverageRectifyCheckBox.Value
                set(app.UnipolarAverageUIAxes, 'YLim', [-10 100], 'XLim', [t_vec(1), t_vec(end)]);
            else
                set(app.UnipolarAverageUIAxes, 'YLim', [-100 100], 'XLim', [t_vec(1), t_vec(end)]);
            end
        end

        function h = init_uni_raster_tab_graphics(app)
            h = struct;
        end

        function h = init_bip_stream_tab_graphics(app)

            xvec = (0:(app.cfg.SAGA.Channels.n.samples-1))./app.cfg.Default.Sample_Rate;
            h = struct('BIP', gobjects(numel(app.cfg.SAGA.Channels.BIP), 1));
            for ii = 1:numel(h.BIP)
                offset = app.BipolarPerChannelOffsetEditField.Value * (ii-1);
                h.BIP(ii) = line(app.BipolarUIAxes, xvec, zeros(size(xvec)) + offset, ...
                    'Color', app.CDATA.BIP(ii,:), ...
                    'LineWidth', 1.5, ...
                    'DisplayName', sprintf('BIP%02d', ii), ...
                    'Visible', 'off' );              
            end
        end

        function h = init_bip_average_tab_graphics(app)
            fs = app.cfg.Default.Sample_Rate;
            sample_indices = round(app.StartBipolarAverageSpinner.Value * fs * 1e-3):round(app.StopBipolarAverageSpinner.Value * fs * 1e-3);
            t_vec = sample_indices / fs * 1e3;
            h = struct('ax', app.BipolarAverageUIAxes, 'mean', gobjects(1,1), 'sd', gobjects(1,1), 'history', nan(app.BipolarAverageNEditField.Value, numel(sample_indices)), 'n', 0, 'n_max', app.BipolarAverageNEditField.Value, 'sample_indices', sample_indices, 'trigs', []);
            npt= numel(sample_indices);
            faces = [1:2*npt, 1];
            verts = [[t_vec'; flipud(t_vec')], [ones(npt,1); -1.*ones(npt,1)]];
            app.BipolarAverageUIAxes.Title.String = "(N = 0)";
            h.sd = patch(app.BipolarAverageUIAxes, 'Faces', faces, 'Vertices', verts, 'FaceColor', [0.65 0.65 0.65], 'DisplayName', '\pm1SD','EdgeColor', 'none');
            h.mean = line(app.BipolarAverageUIAxes, t_vec, zeros(size(t_vec)), 'Color', 'k', 'LineWidth', 2.5, 'DisplayName', 'Mean');
            
            if app.BipolarAverageRectifyCheckBox.Value
                set(app.BipolarAverageUIAxes, 'YLim', [-10 100], 'XLim', [t_vec(1), t_vec(end)]);
            else
                set(app.BipolarAverageUIAxes, 'YLim', [-100 100], 'XLim', [t_vec(1), t_vec(end)]);
            end
        end

        function h = init_ica_stream_tab_graphics(app)

            xvec = (0:(app.cfg.SAGA.Channels.n.samples-1))./app.cfg.Default.Sample_Rate;
            h = struct('ICA', gobjects(app.cfg.Default.N_ICs, 1));
            for ii = 1:numel(h.ICA)
                offset = app.ICA_OFFSET * (ii-1);
                h.ICA(ii) = line(app.ICAUIAxes, xvec, zeros(size(xvec)) + offset, ...
                    'Color', app.CDATA.ICA(ii,:), ...
                    'LineWidth', 1.5, ...
                    'DisplayName', sprintf('ICA%02d', ii), ...
                    'Visible', 'off' );                
            end
        end

        function h = init_ica_raster_tab_graphics(app)
            h = struct;
        end

        function h = init_rms_contour_tab_graphics(app)
            h = struct;
        end
        
        function shift_in_new_averaging_snippets_(app, snippets)
            if isempty(snippets)
                return;
            end
            n_snips = size(snippets,1);
            app.H.n = min(app.H.n+n_snips, app.H.n_max);
            app.H.history = circshift(app.H.history, n_snips, 1);
            app.H.history(1:n_snips,:) = snippets;
            mu = nanmean(app.H.history(1:app.H.n,:), 1); %#ok<*NANMEAN> 
            sd = nanstd(app.H.history(1:app.H.n,:), [], 1); %#ok<*NANSTD> 
            app.H.mean.YData = mu;
            app.H.sd.Vertices(:,2) = [(mu + sd)'; flipud((mu - sd)')];
            app.H.ax.Title.String = sprintf('(N = %d)', app.H.n);
            drawnow;
        end
        
        function parse_sync_trigs_(app, data)
            sync_bit = app.TriggerSyncBitEditField.Value;
            sync_data = bitand(data, 2^sync_bit) ~= 2^sync_bit;
            app.H.trigs = find([false; diff(sync_data(:)) > 0]);
        end
    end

    methods (Static, Access = protected)
        function WipeTabGraphics(tab)
            %WIPETABGRAPHICS  Delete all child-axes graphics, given a tab.
            h = findobj(tab.Children, 'Type', 'axes');
            delete(h.Children);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tag, config, varargin)
            app.Tag = tag;
            % If this is the second SAGA figure move it right.
            if strcmpi(tag, "B")
                app.SAGADataViewerUIFigure.Position(1) = app.SAGADataViewerUIFigure.Position(1) + app.SAGADataViewerUIFigure.Position(3)*0.85;
            end
            % Setup the title and stuff:
            set(app.SAGADataViewerUIFigure, ...
                'Name', sprintf("SAGA-%s Data Viewer", app.Tag), ...
                'Tag', sprintf('fig.DataViewer.%s', app.Tag));

            % Parse configuration
            if nargin < 3
                config = parse_main_config();
            end
            app.cfg = struct('SAGA', config.SAGA.(tag), ...
                             'Default', config.Default, ...
                             'Server', config.Server);    
            f = setdiff(string(fieldnames(app.cfg.SAGA.Channels)), "n");
            for iF = 1:numel(f)
                app.cfg.SAGA.Channels.en.(f(iF)) = false(size(app.cfg.SAGA.Channels.(f(iF))));
            end
            app.cfg.SAGA.Channels.ICA = 1:app.cfg.Default.N_ICs;
            app.cfg.SAGA.Channels.en.ICA = false(size(app.cfg.SAGA.Channels.ICA));
            
            app.udp = udpport("byte");
            app.tcp = tcpserver(config.Server.Address.TCP, config.Server.TCP.(tag).Parameters, ...
                'ConnectionChangedFcn', @(src,evt)app.setConnectionStatus(src,evt));
            
            % Initialize stream colors and x-axis sample times vector.
            cdata = app.init_cdata();
            app.n.all_samples = app.cfg.SAGA.Channels.n.samples;
            app.n.mid_sample = round(app.n.all_samples/2);
            
            % Create struct to store the unipolar stream lines in.
            app.H = app.init_uni_stream_tab_graphics();
            for ii = 1:64
                app.(sprintf('UNI%02dSwitch',ii)).FontColor = cdata.UNI(ii,:);
                app.(sprintf('UNI%02dSwitchLabel', ii)).FontColor = cdata.UNI(ii,:);   
            end
            
            % Create struct to store the bipolar stream lines in.
            for ii = 1:numel(app.cfg.SAGA.Channels.BIP)
                app.(sprintf('BIP%02dSwitch',ii)).FontColor = cdata.BIP(ii,:);
                app.(sprintf('BIP%02dSwitchLabel', ii)).FontColor = cdata.BIP(ii,:);                
            end
            
            % Create struct to store the ICA stream lines in.
            for ii = 1:numel(app.cfg.Default.N_ICs)
                app.(sprintf('ICA%02dSwitch',ii)).FontColor = cdata.ICA(ii,:);
                app.(sprintf('ICA%02dSwitchLabel', ii)).FontColor = cdata.ICA(ii,:);                 
            end
            iUnused = 21 - (20 - app.cfg.Default.N_ICs);
            for ii = iUnused:20 % If there are less default N_ICs configured, hide the ones that won't be used.
                app.(sprintf('ICA%02dSwitch',ii)).Visible = 'off';
                app.(sprintf('ICA%02dSwitchLabel', ii)).Visible = 'off';   
            end
            
            app.init_filters();
            
            app.SAGALabel.Text = sprintf("SAGA-%s", app.Tag);
            app.setDateTimeLabel(datetime('now'));
            app.enable_unipolar_stream(1:4);
            app.enable_bipolar_stream(1:4);
            app.enable_ica_stream(1:min(4, app.cfg.Default.N_ICs));

            % Settings for the UIAxes
            set(app.UnipolarUIAxes, 'Interactions', [zoomInteraction rulerPanInteraction('Dimension', 'xy')]);
            set(app.BipolarUIAxes, 'Interactions', [zoomInteraction rulerPanInteraction('Dimension', 'xy')]);
            set(app.UnipolarAverageUIAxes, 'Interactions', [zoomInteraction rulerPanInteraction('Dimension', 'xy')]);
            set(app.BipolarAverageUIAxes, 'Interactions', [zoomInteraction rulerPanInteraction('Dimension', 'xy')]);
            set(app.ICAUIAxes, 'Interactions', [zoomInteraction rulerPanInteraction('Dimension', 'xy')]);
            fprintf(1,'\t->\tStartup complete: Data-Visualizer-%s\n', app.Tag);
        end

        % Value changed function: ToggleAllUnipolarChannelsSwitch
        function ToggleAllUnipolarChannelsSwitchValueChanged(app, event)
            if strcmpi(event.PreviousValue, 'On')
                 app.disable_unipolar_stream(1:64);
            else
                app.enable_unipolar_stream(1:64);
            end
            app.send_uni_stream_udp_message();
        end

        % Value changed function: ToggleAllBipolarChannelsSwitch
        function ToggleAllBipolarChannelsSwitchValueChanged(app, event)
            if strcmpi(event.PreviousValue, 'On')
                 app.disable_bipolar_stream(1:4);
            else
                app.enable_bipolar_stream(1:4);
            end
            app.send_bip_stream_udp_message();
        end

        % Value changed function: UNI01Switch, UNI02Switch, UNI03Switch, 
        % ...and 61 other components
        function UnipolarChannelSwitchValueChanged(app, event)
            if strcmpi(event.PreviousValue, 'On')
                app.disable_unipolar_stream(str2double(event.Source.Tag));
            else
                app.enable_unipolar_stream(str2double(event.Source.Tag));
            end
            app.send_uni_stream_udp_message();
        end

        % Value changed function: BIP01Switch, BIP02Switch, BIP03Switch, 
        % ...and 1 other component
        function BipolarSwitchValueChanged(app, event)
            if strcmpi(event.PreviousValue, 'On')
                app.disable_bipolar_stream(str2double(event.Source.Tag));
            else
                app.enable_bipolar_stream(str2double(event.Source.Tag));
            end
            app.send_bip_stream_udp_message();
        end

        % Callback function: BipolarAverageCutoff1EditField, 
        % ...and 23 other components
        function FilterParameterValueChanged(app, event)
            app.init_filters();
            switch app.mode
                case 'UA'
                    SAGA_Data_Visualizer.WipeTabGraphics(app.TabGroup.SelectedTab);
                    app.H = app.init_uni_average_tab_graphics();
                case 'BA'
                    SAGA_Data_Visualizer.WipeTabGraphics(app.TabGroup.SelectedTab);
                    app.H = app.init_bip_average_tab_graphics();
            end
        end

        % Value changed function: BipolarPerChannelOffsetEditField, 
        % ...and 1 other component
        function PerChannelOffsetEditFieldValueChanged(app, event)
            prev = event.PreviousValue;
            value = event.Value;
            dy = value - prev;
            h = app.H.(event.Source.Tag);
            for ii = 1:numel(h)
                new_offset = (ii - 1) * dy;
                h(ii).YData = h(ii).YData + new_offset;
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = event.NewValue;
            app.mode = char(selectedTab.Tag);

            % First, delete unused graphics from the tab we just left.
            previousTab = event.OldValue;
            SAGA_Data_Visualizer.WipeTabGraphics(previousTab);

            % Then, initialize graphics objects on the new tab, and send
            % message to the stream service indicating new configuration
            % for sending data:
            switch app.mode
                case 'US'
                    app.H = app.init_uni_stream_tab_graphics();
                    app.send_uni_stream_udp_message();
                case 'UA'
                    app.H = app.init_uni_average_tab_graphics();
                    app.send_uni_average_udp_message();
                case 'BS'
                    app.H = app.init_bip_stream_tab_graphics();
                    app.send_bip_stream_udp_message();
                case 'BA'
                    app.H = app.init_bip_average_tab_graphics();
                    app.send_bip_average_udp_message();
%                 case 'IS'
%                     app.H = app.init_ica_stream_tab_graphics();
%                     app.send_ica_stream_udp_message();
%                 case 'IR'
%                     app.H = app.init_ica_raster_tab_graphics();
%                     app.send_mode_only_udp_message();  
%                 case 'UR'
%                     app.H = app.init_uni_raster_tab_graphics();
%                     app.send_mode_only_udp_message();  
%                 case 'RC'
%                     app.H = app.init_rms_contour_tab_graphics();
%                     app.send_mode_only_udp_message();  
            end
            
        end

        % Value changed function: ToggleAllICAChannelsSwitch
        function ToggleAllICAChannelsSwitchValueChanged(app, event)
            if strcmpi(event.PreviousValue, 'On')
                app.disable_ica_stream(1:numel(app.H.ICA));
            else
                app.enable_ica_stream(1:numel(app.H.ICA));
            end
            app.send_ica_stream_udp_message();
        end

        % Value changed function: ICA01Switch, ICA02Switch, ICA03Switch, 
        % ...and 17 other components
        function ICASwitchValueChanged(app, event)
            if strcmpi(event.PreviousValue, 'On')
                app.disable_ica_stream(str2double(event.Source.Tag));
            else
                app.enable_ica_stream(str2double(event.Source.Tag));
            end
            app.send_ica_stream_udp_message();
        end

        % Value changed function: UnipolarAverageChannelEditField
        function UnipolarAverageChannelEditFieldValueChanged(app, event)
            value = app.UnipolarAverageChannelEditField.Value;
            app.UnipolarAverageUIAxes.Title.String = sprintf("Unipolar Stim-Triggered Average: Ch-%02d", value);  
            SAGA_Data_Visualizer.WipeTabGraphics(app.TabGroup.SelectedTab);
            app.init_filters();
            app.send_bip_average_udp_message();
        end

        % Value changed function: BipolarAverageChannelEditField
        function BipolarAverageChannelEditFieldValueChanged(app, event)
            value = app.BipolarAverageChannelEditField.Value;
            app.BipolarAverageUIAxes.Title.String = sprintf("Bipolar Stim-Triggered Average: Ch-%02d", value);  
            SAGA_Data_Visualizer.WipeTabGraphics(app.TabGroup.SelectedTab);
            app.init_filters();
            app.send_uni_average_udp_message();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SAGADataViewerUIFigure = uifigure('Visible', 'off');
            app.SAGADataViewerUIFigure.Name = 'SAGA Data Viewer';
            app.SAGADataViewerUIFigure.Icon = 'outline_live_tv_black_24dp.png';
            app.TabGroup = uitabgroup(app.SAGADataViewerUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.UnipolarStreamTab = uitab(app.TabGroup);
            app.UnipolarStreamTab.Title = 'Unipolar Stream';
            app.UnipolarStreamTab.Tag = 'US';
            app.UnipolarStreamGridLayout = uigridlayout(app.UnipolarStreamTab);
            app.UnipolarUIAxes = uiaxes(app.UnipolarStreamGridLayout);
            title(app.UnipolarUIAxes, 'Unipolar Streams')
            xlabel(app.UnipolarUIAxes, 'Time (s)')
            ylabel(app.UnipolarUIAxes, 'Amplitude (\muV)')
            zlabel(app.UnipolarUIAxes, 'Z')
            app.UnipolarStreamChannelsPanel = uipanel(app.UnipolarStreamGridLayout);
            app.UnipolarStreamChannelsPanel.Title = 'Unipolar Stream Channels';
            app.UNI01SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI01SwitchLabel.Text = 'UNI01';
            app.UNI01Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI01Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI01Switch.Tag = '1';
            app.UNI02Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI02Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI02Switch.Tag = '2';
            app.UNI02SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI02SwitchLabel.Text = 'UNI02';
            app.UNI03Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI03Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI03Switch.Tag = '3';
            app.UNI03SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI03SwitchLabel.Text = 'UNI03';
            app.UNI04Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI04Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI04Switch.Tag = '4';
            app.UNI04SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI04SwitchLabel.Tag = '4';
            app.UNI04SwitchLabel.Text = 'UNI04';
            app.UNI05Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI05Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI05Switch.Tag = '5';
            app.UNI05SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI05SwitchLabel.Tag = '5';
            app.UNI05SwitchLabel.Text = 'UNI05';
            app.UNI06Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI06Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI06Switch.Tag = '6';
            app.UNI06SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI06SwitchLabel.Tag = '6';
            app.UNI06SwitchLabel.Text = 'UNI06';
            app.UNI07Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI07Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI07Switch.Tag = '7';
            app.UNI07SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI07SwitchLabel.Tag = '7';
            app.UNI07SwitchLabel.Text = 'UNI07';
            app.UNI08Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI08Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI08Switch.Tag = '8';
            app.UNI08SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI08SwitchLabel.Tag = '8';
            app.UNI08SwitchLabel.Text = 'UNI08';
            app.UNI09Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI09Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI09Switch.Tag = '9';
            app.UNI09SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI09SwitchLabel.Text = 'UNI09';
            app.UNI10Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI10Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI10Switch.Tag = '10';
            app.UNI10SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI10SwitchLabel.Text = 'UNI10';
            app.UNI11Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI11Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI11Switch.Tag = '11';
            app.UNI11SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI11SwitchLabel.Text = 'UNI11';
            app.UNI12Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI12Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI12Switch.Tag = '12';
            app.UNI12SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI12SwitchLabel.Tag = '4';
            app.UNI12SwitchLabel.Text = 'UNI12';
            app.UNI13Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI13Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI13Switch.Tag = '13';
            app.UNI13SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI13SwitchLabel.Tag = '5';
            app.UNI13SwitchLabel.Text = 'UNI13';
            app.UNI14Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI14Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI14Switch.Tag = '14';
            app.UNI14SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI14SwitchLabel.Tag = '6';
            app.UNI14SwitchLabel.Text = 'UNI14';
            app.UNI15Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI15Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI15Switch.Tag = '15';
            app.UNI15SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI15SwitchLabel.Tag = '7';
            app.UNI15SwitchLabel.Text = 'UNI15';
            app.UNI16Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI16Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI16Switch.Tag = '16';
            app.UNI16SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI16SwitchLabel.Tag = '8';
            app.UNI16SwitchLabel.Text = 'UNI16';
            app.UNI17Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI17Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI17Switch.Tag = '17';
            app.UNI17SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI17SwitchLabel.Text = 'UNI17';
            app.UNI18Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI18Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI18Switch.Tag = '18';
            app.UNI18SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI18SwitchLabel.Text = 'UNI18';
            app.UNI19Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI19Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI19Switch.Tag = '19';
            app.UNI19SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI19SwitchLabel.Text = 'UNI19';
            app.UNI20Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI20Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI20Switch.Tag = '20';
            app.UNI20SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI20SwitchLabel.Tag = '4';
            app.UNI20SwitchLabel.Text = 'UNI20';
            app.UNI21Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI21Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI21Switch.Tag = '21';
            app.UNI21SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI21SwitchLabel.Tag = '5';
            app.UNI21SwitchLabel.Text = 'UNI21';
            app.UNI22Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI22Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI22Switch.Tag = '22';
            app.UNI22SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI22SwitchLabel.Tag = '6';
            app.UNI22SwitchLabel.Text = 'UNI22';
            app.UNI23Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI23Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI23Switch.Tag = '23';
            app.UNI23SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI23SwitchLabel.Tag = '7';
            app.UNI23SwitchLabel.Text = 'UNI23';
            app.UNI24Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI24Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI24Switch.Tag = '24';
            app.UNI24SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI24SwitchLabel.Tag = '8';
            app.UNI24SwitchLabel.Text = 'UNI24';
            app.UNI25Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI25Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI25Switch.Tag = '25';
            app.UNI25SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI25SwitchLabel.Text = 'UNI25';
            app.UNI26Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI26Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI26Switch.Tag = '26';
            app.UNI26SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI26SwitchLabel.Text = 'UNI26';
            app.UNI27Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI27Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI27Switch.Tag = '27';
            app.UNI27SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI27SwitchLabel.Text = 'UNI27';
            app.UNI28Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI28Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI28Switch.Tag = '28';
            app.UNI28SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI28SwitchLabel.Tag = '4';
            app.UNI28SwitchLabel.Text = 'UNI28';
            app.UNI29Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI29Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI29Switch.Tag = '29';
            app.UNI29SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI29SwitchLabel.Tag = '5';
            app.UNI29SwitchLabel.Text = 'UNI29';
            app.UNI30Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI30Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI30Switch.Tag = '30';
            app.UNI30SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI30SwitchLabel.Tag = '6';
            app.UNI30SwitchLabel.Text = 'UNI30';
            app.UNI31Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI31Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI31Switch.Tag = '31';
            app.UNI31SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI31SwitchLabel.Tag = '7';
            app.UNI31SwitchLabel.Text = 'UNI31';
            app.UNI32Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI32Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI32Switch.Tag = '32';
            app.UNI32SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI32SwitchLabel.Tag = '8';
            app.UNI32SwitchLabel.Text = 'UNI32';
            app.UNI33Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI33Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI33Switch.Tag = '33';
            app.UNI33SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI33SwitchLabel.Text = 'UNI33';
            app.UNI34Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI34Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI34Switch.Tag = '34';
            app.UNI34SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI34SwitchLabel.Text = 'UNI34';
            app.UNI35Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI35Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI35Switch.Tag = '35';
            app.UNI35SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI35SwitchLabel.Text = 'UNI35';
            app.UNI36Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI36Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI36Switch.Tag = '36';
            app.UNI36SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI36SwitchLabel.Tag = '4';
            app.UNI36SwitchLabel.Text = 'UNI36';
            app.UNI37Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI37Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI37Switch.Tag = '37';
            app.UNI37SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI37SwitchLabel.Tag = '5';
            app.UNI37SwitchLabel.Text = 'UNI37';
            app.UNI38Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI38Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI38Switch.Tag = '38';
            app.UNI38SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI38SwitchLabel.Tag = '6';
            app.UNI38SwitchLabel.Text = 'UNI38';
            app.UNI39Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI39Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI39Switch.Tag = '39';
            app.UNI39SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI39SwitchLabel.Tag = '7';
            app.UNI39SwitchLabel.Text = 'UNI39';
            app.UNI40Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI40Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI40Switch.Tag = '40';
            app.UNI40SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI40SwitchLabel.Tag = '8';
            app.UNI40SwitchLabel.Text = 'UNI40';
            app.UNI41Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI41Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI41Switch.Tag = '41';
            app.UNI41SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI41SwitchLabel.Text = 'UNI41';
            app.UNI42Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI42Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI42Switch.Tag = '42';
            app.UNI42SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI42SwitchLabel.Text = 'UNI42';
            app.UNI43Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI43Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI43Switch.Tag = '43';
            app.UNI43SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI43SwitchLabel.Text = 'UNI43';
            app.UNI44Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI44Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI44Switch.Tag = '44';
            app.UNI44SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI44SwitchLabel.Tag = '4';
            app.UNI44SwitchLabel.Text = 'UNI44';
            app.UNI45Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI45Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI45Switch.Tag = '45';
            app.UNI45SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI45SwitchLabel.Tag = '5';
            app.UNI45SwitchLabel.Text = 'UNI45';
            app.UNI46Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI46Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI46Switch.Tag = '46';
            app.UNI46SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI46SwitchLabel.Tag = '6';
            app.UNI46SwitchLabel.Text = 'UNI46';
            app.UNI47Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI47Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI47Switch.Tag = '47';
            app.UNI47SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI47SwitchLabel.Tag = '7';
            app.UNI47SwitchLabel.Text = 'UNI47';
            app.UNI48Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI48Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI48Switch.Tag = '48';
            app.UNI48SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI48SwitchLabel.Tag = '8';
            app.UNI48SwitchLabel.Text = 'UNI48';
            app.UNI49Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI49Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI49Switch.Tag = '49';
            app.UNI49SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI49SwitchLabel.Text = 'UNI49';
            app.UNI50Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI50Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI50Switch.Tag = '50';
            app.UNI50SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI50SwitchLabel.Text = 'UNI50';
            app.UNI51Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI51Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI51Switch.Tag = '51';
            app.UNI51SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI51SwitchLabel.Text = 'UNI51';
            app.UNI52Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI52Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI52Switch.Tag = '52';
            app.UNI52SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI52SwitchLabel.Tag = '4';
            app.UNI52SwitchLabel.Text = 'UNI52';
            app.UNI53Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI53Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI53Switch.Tag = '53';
            app.UNI53SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI53SwitchLabel.Tag = '5';
            app.UNI53SwitchLabel.Text = 'UNI53';
            app.UNI54Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI54Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI54Switch.Tag = '54';
            app.UNI54SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI54SwitchLabel.Tag = '6';
            app.UNI54SwitchLabel.Text = 'UNI54';
            app.UNI55Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI55Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI55Switch.Tag = '55';
            app.UNI55SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI55SwitchLabel.Tag = '7';
            app.UNI55SwitchLabel.Text = 'UNI55';
            app.UNI56Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI56Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI56Switch.Tag = '56';
            app.UNI56SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI56SwitchLabel.Tag = '8';
            app.UNI56SwitchLabel.Text = 'UNI56';
            app.UNI57Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI57Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI57Switch.Tag = '57';
            app.UNI57SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI57SwitchLabel.Text = 'UNI57';
            app.UNI58Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI58Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI58Switch.Tag = '58';
            app.UNI58SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI58SwitchLabel.Text = 'UNI58';
            app.UNI59Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI59Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI59Switch.Tag = '59';
            app.UNI59SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI59SwitchLabel.Text = 'UNI59';
            app.UNI60Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI60Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI60Switch.Tag = '60';
            app.UNI60SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI60SwitchLabel.Tag = '4';
            app.UNI60SwitchLabel.Text = 'UNI60';
            app.UNI61Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI61Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI61Switch.Tag = '61';
            app.UNI61SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI61SwitchLabel.Tag = '5';
            app.UNI61SwitchLabel.Text = 'UNI61';
            app.UNI62Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI62Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI62Switch.Tag = '62';
            app.UNI62SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI62SwitchLabel.Tag = '6';
            app.UNI62SwitchLabel.Text = 'UNI62';
            app.UNI63Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI63Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI63Switch.Tag = '63';
            app.UNI63SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI63SwitchLabel.Tag = '7';
            app.UNI63SwitchLabel.Text = 'UNI63';
            app.UNI64Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI64Switch.ValueChangedFcn = createCallbackFcn(app, @UnipolarChannelSwitchValueChanged, true);
            app.UNI64Switch.Tag = '64';
            app.UNI64SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI64SwitchLabel.Tag = '8';
            app.UNI64SwitchLabel.Text = 'UNI64';
            app.UnipolarStreamSettingsPanel = uipanel(app.UnipolarStreamGridLayout);
            app.UnipolarStreamSettingsPanel.Title = 'Unipolar Stream Settings';
            app.AllChannelsSwitchLabel = uilabel(app.UnipolarStreamSettingsPanel);
            app.AllChannelsSwitchLabel.Text = 'All Channels';
            app.ToggleAllUnipolarChannelsSwitch = uiswitch(app.UnipolarStreamSettingsPanel, 'toggle');
            app.ToggleAllUnipolarChannelsSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleAllUnipolarChannelsSwitchValueChanged, true);
            app.PerChannelOffsetVEditFieldLabel = uilabel(app.UnipolarStreamSettingsPanel);
            app.PerChannelOffsetVEditFieldLabel.Text = 'Per-Channel Offset (µV)';
            app.UnipolarPerChannelOffsetEditField = uieditfield(app.UnipolarStreamSettingsPanel, 'numeric');
            app.UnipolarPerChannelOffsetEditField.Limits = [0 5000];
            app.UnipolarPerChannelOffsetEditField.RoundFractionalValues = 'on';
            app.UnipolarPerChannelOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @PerChannelOffsetEditFieldValueChanged, true);
            app.UnipolarPerChannelOffsetEditField.Tag = 'UNI';
            app.UnipolarPerChannelOffsetEditField.Value = 200;
            app.UnipolarFilterCheckBox = uicheckbox(app.UnipolarStreamSettingsPanel);
            app.UnipolarFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarFilterCheckBox.Text = 'Filter';
            app.UnipolarFilterCheckBox.Value = true;
            app.Cutoff1HzEditFieldLabel = uilabel(app.UnipolarStreamSettingsPanel);
            app.Cutoff1HzEditFieldLabel.Text = 'Cutoff 1 (Hz)';
            app.UnipolarCutoff1EditField = uieditfield(app.UnipolarStreamSettingsPanel, 'numeric');
            app.UnipolarCutoff1EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarCutoff1EditField.Tag = 'fc_low';
            app.UnipolarCutoff1EditField.Value = 25;
            app.Cutoff2HzEditFieldLabel = uilabel(app.UnipolarStreamSettingsPanel);
            app.Cutoff2HzEditFieldLabel.Text = 'Cutoff 2 (Hz)';
            app.UnipolarCutoff2EditField = uieditfield(app.UnipolarStreamSettingsPanel, 'numeric');
            app.UnipolarCutoff2EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarCutoff2EditField.Tag = 'fc_high';
            app.UnipolarCutoff2EditField.Value = 400;
            app.UnipolarCARCheckBox = uicheckbox(app.UnipolarStreamSettingsPanel);
            app.UnipolarCARCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarCARCheckBox.Text = 'CAR';
            app.UnipolarCARCheckBox.Value = true;
            app.UnipolarAverageTab = uitab(app.TabGroup);
            app.UnipolarAverageTab.Title = 'Unipolar Average';
            app.UnipolarAverageTab.Tag = 'UA';
            app.UnipolarAverageGridLayout = uigridlayout(app.UnipolarAverageTab);
            app.UnipolarAverageUIAxes = uiaxes(app.UnipolarAverageGridLayout);
            title(app.UnipolarAverageUIAxes, 'Unipolar Stim-Triggered Average: Ch-10')
            xlabel(app.UnipolarAverageUIAxes, 'Time (ms)')
            ylabel(app.UnipolarAverageUIAxes, 'EMG Amplitude (\muV)')
            zlabel(app.UnipolarAverageUIAxes, 'Z')
            app.UnipolarAverageSettingsPanel = uipanel(app.UnipolarAverageGridLayout);
            app.UnipolarAverageSettingsPanel.Title = 'Unipolar Average Settings';
            app.UNIChannelEditFieldLabel = uilabel(app.UnipolarAverageSettingsPanel);
            app.UNIChannelEditFieldLabel.Text = 'UNI Channel';
            app.UnipolarAverageChannelEditField = uieditfield(app.UnipolarAverageSettingsPanel, 'numeric');
            app.UnipolarAverageChannelEditField.ValueChangedFcn = createCallbackFcn(app, @UnipolarAverageChannelEditFieldValueChanged, true);
            app.UnipolarAverageChannelEditField.Value = 10;
            app.UnipolarAverageFilterCheckBox = uicheckbox(app.UnipolarAverageSettingsPanel);
            app.UnipolarAverageFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarAverageFilterCheckBox.Text = 'Filter';
            app.UnipolarAverageFilterCheckBox.Value = true;
            app.Cutoff1HzEditFieldLabel_2 = uilabel(app.UnipolarAverageSettingsPanel);
            app.Cutoff1HzEditFieldLabel_2.Text = 'Cutoff 1 (Hz)';
            app.UnipolarAverageCutoff1EditField = uieditfield(app.UnipolarAverageSettingsPanel, 'numeric');
            app.UnipolarAverageCutoff1EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarAverageCutoff1EditField.Tag = 'fc_low';
            app.UnipolarAverageCutoff1EditField.Value = 25;
            app.Cutoff2HzEditFieldLabel_2 = uilabel(app.UnipolarAverageSettingsPanel);
            app.Cutoff2HzEditFieldLabel_2.Text = 'Cutoff 2 (Hz)';
            app.UnipolarAverageCutoff2EditField = uieditfield(app.UnipolarAverageSettingsPanel, 'numeric');
            app.UnipolarAverageCutoff2EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarAverageCutoff2EditField.Tag = 'fc_high';
            app.UnipolarAverageCutoff2EditField.Value = 400;
            app.UnipolarAverageCARCheckBox = uicheckbox(app.UnipolarAverageSettingsPanel);
            app.UnipolarAverageCARCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarAverageCARCheckBox.Text = 'CAR';
            app.UnipolarAverageCARCheckBox.Value = true;
            app.StartDatamsSpinnerLabel = uilabel(app.UnipolarAverageSettingsPanel);
            app.StartDatamsSpinnerLabel.Text = 'Start Data (ms)';
            app.StartUnipolarAverageSpinner = uispinner(app.UnipolarAverageSettingsPanel);
            app.StartUnipolarAverageSpinner.Step = 5;
            app.StartUnipolarAverageSpinner.Limits = [-250 0];
            app.StartUnipolarAverageSpinner.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.StartUnipolarAverageSpinner.Value = -20;
            app.StopDatamsSpinnerLabel = uilabel(app.UnipolarAverageSettingsPanel);
            app.StopDatamsSpinnerLabel.Text = 'Stop Data (ms)';
            app.StopUnipolarAverageSpinner = uispinner(app.UnipolarAverageSettingsPanel);
            app.StopUnipolarAverageSpinner.Step = 5;
            app.StopUnipolarAverageSpinner.Limits = [0 250];
            app.StopUnipolarAverageSpinner.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.StopUnipolarAverageSpinner.Value = 40;
            app.NEditFieldLabel = uilabel(app.UnipolarAverageSettingsPanel);
            app.NEditFieldLabel.Text = 'N';
            app.UnipolarAverageNEditField = uieditfield(app.UnipolarAverageSettingsPanel, 'numeric');
            app.UnipolarAverageNEditField.Limits = [1 100];
            app.UnipolarAverageNEditField.RoundFractionalValues = 'on';
            app.UnipolarAverageNEditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarAverageNEditField.Value = 30;
            app.ResetUnipolarAverageButton = uibutton(app.UnipolarAverageSettingsPanel, 'push');
            app.ResetUnipolarAverageButton.ButtonPushedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.ResetUnipolarAverageButton.Text = 'Reset';
            app.UnipolarAverageRectifyCheckBox = uicheckbox(app.UnipolarAverageSettingsPanel);
            app.UnipolarAverageRectifyCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.UnipolarAverageRectifyCheckBox.Text = 'Rectify';
            app.UnipolarAverageRectifyCheckBox.Value = true;
            app.UnipolarRasterTab = uitab(app.TabGroup);
            app.UnipolarRasterTab.Title = 'Unipolar Raster';
            app.UnipolarRasterTab.Tag = 'UR';
            app.UnipolarRasterGridLayout = uigridlayout(app.UnipolarRasterTab);
            app.UnipolarRasterSettingsPanel = uipanel(app.UnipolarRasterGridLayout);
            app.UnipolarRasterSettingsPanel.Title = 'Unipolar Raster Settings';
            app.BipolarStreamTab = uitab(app.TabGroup);
            app.BipolarStreamTab.Title = 'Bipolar Stream';
            app.BipolarStreamTab.Tag = 'BS';
            app.BipolarStreamGridLayout = uigridlayout(app.BipolarStreamTab);
            app.BipolarUIAxes = uiaxes(app.BipolarStreamGridLayout);
            title(app.BipolarUIAxes, 'Bipolar Streams')
            xlabel(app.BipolarUIAxes, 'Time (s)')
            ylabel(app.BipolarUIAxes, 'Amplitude (\muV)')
            zlabel(app.BipolarUIAxes, 'Z')
            app.BipolarStreamChannelsPanel = uipanel(app.BipolarStreamGridLayout);
            app.BipolarStreamChannelsPanel.Title = 'Bipolar Stream Channels';
            app.BIP01SwitchLabel = uilabel(app.BipolarStreamChannelsPanel);
            app.BIP01SwitchLabel.Text = 'BIP01';
            app.BIP01Switch = uiswitch(app.BipolarStreamChannelsPanel, 'slider');
            app.BIP01Switch.ValueChangedFcn = createCallbackFcn(app, @BipolarSwitchValueChanged, true);
            app.BIP01Switch.Tag = '1';
            app.BIP02Switch = uiswitch(app.BipolarStreamChannelsPanel, 'slider');
            app.BIP02Switch.ValueChangedFcn = createCallbackFcn(app, @BipolarSwitchValueChanged, true);
            app.BIP02Switch.Tag = '2';
            app.BIP02SwitchLabel = uilabel(app.BipolarStreamChannelsPanel);
            app.BIP02SwitchLabel.Text = 'BIP02';
            app.BIP03Switch = uiswitch(app.BipolarStreamChannelsPanel, 'slider');
            app.BIP03Switch.ValueChangedFcn = createCallbackFcn(app, @BipolarSwitchValueChanged, true);
            app.BIP03Switch.Tag = '3';
            app.BIP03SwitchLabel = uilabel(app.BipolarStreamChannelsPanel);
            app.BIP03SwitchLabel.Text = 'BIP03';
            app.BIP04Switch = uiswitch(app.BipolarStreamChannelsPanel, 'slider');
            app.BIP04Switch.ValueChangedFcn = createCallbackFcn(app, @BipolarSwitchValueChanged, true);
            app.BIP04Switch.Tag = '4';
            app.BIP04SwitchLabel = uilabel(app.BipolarStreamChannelsPanel);
            app.BIP04SwitchLabel.Tag = '4';
            app.BIP04SwitchLabel.Text = 'BIP04';
            app.BipolarStreamSettingsPanel = uipanel(app.BipolarStreamGridLayout);
            app.BipolarStreamSettingsPanel.Title = 'Bipolar Stream Settings';
            app.ToggleAllBipolarChannelsSwitchLabel = uilabel(app.BipolarStreamSettingsPanel);
            app.ToggleAllBipolarChannelsSwitchLabel.Text = 'All Channels';
            app.ToggleAllBipolarChannelsSwitch = uiswitch(app.BipolarStreamSettingsPanel, 'toggle');
            app.ToggleAllBipolarChannelsSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleAllBipolarChannelsSwitchValueChanged, true);
            app.ToggleAllBipolarChannelsSwitch.Value = 'On';
            app.BipolarFilterCheckBox = uicheckbox(app.BipolarStreamSettingsPanel);
            app.BipolarFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.BipolarFilterCheckBox.Text = 'Filter';
            app.BipolarFilterCheckBox.Value = true;
            app.BipolarPerChannelOffsetVEditFieldLabel = uilabel(app.BipolarStreamSettingsPanel);
            app.BipolarPerChannelOffsetVEditFieldLabel.Text = 'Per-Channel Offset (µV)';
            app.BipolarPerChannelOffsetEditField = uieditfield(app.BipolarStreamSettingsPanel, 'numeric');
            app.BipolarPerChannelOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @PerChannelOffsetEditFieldValueChanged, true);
            app.BipolarPerChannelOffsetEditField.Tag = 'BIP';
            app.BipolarPerChannelOffsetEditField.Value = 200;
            app.BipolarCutoff1EditFieldLabel = uilabel(app.BipolarStreamSettingsPanel);
            app.BipolarCutoff1EditFieldLabel.Text = 'Cutoff 1 (Hz)';
            app.BipolarCutoff1EditField = uieditfield(app.BipolarStreamSettingsPanel, 'numeric');
            app.BipolarCutoff1EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.BipolarCutoff1EditField.Tag = 'fc_low';
            app.BipolarCutoff1EditField.Value = 25;
            app.BipolarCutoff2EditFieldLabel = uilabel(app.BipolarStreamSettingsPanel);
            app.BipolarCutoff2EditFieldLabel.Text = 'Cutoff 2 (Hz)';
            app.BipolarCutoff2EditField = uieditfield(app.BipolarStreamSettingsPanel, 'numeric');
            app.BipolarCutoff2EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.BipolarCutoff2EditField.Tag = 'fc_high';
            app.BipolarCutoff2EditField.Value = 400;
            app.BipolarAverageTab = uitab(app.TabGroup);
            app.BipolarAverageTab.Title = 'Bipolar Average';
            app.BipolarAverageTab.Tag = 'BA';
            app.BipolarAverageGridLayout = uigridlayout(app.BipolarAverageTab);
            app.BipolarAverageUIAxes = uiaxes(app.BipolarAverageGridLayout);
            title(app.BipolarAverageUIAxes, 'Bipolar Stim-Triggered Average: Ch-01')
            xlabel(app.BipolarAverageUIAxes, 'Time (ms)')
            ylabel(app.BipolarAverageUIAxes, 'EMG Amplitude (\muV)')
            zlabel(app.BipolarAverageUIAxes, 'Z')
            app.BipolarAverageSettingsPanel = uipanel(app.BipolarAverageGridLayout);
            app.BipolarAverageSettingsPanel.Title = 'Bipolar Average Settings';
            app.BipolarAverageFilterCheckBox = uicheckbox(app.BipolarAverageSettingsPanel);
            app.BipolarAverageFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.BipolarAverageFilterCheckBox.Text = 'Filter';
            app.BipolarAverageFilterCheckBox.Value = true;
            app.BIPChannelLabel = uilabel(app.BipolarAverageSettingsPanel);
            app.BIPChannelLabel.Text = 'BIP Channel';
            app.BipolarAverageChannelEditField = uieditfield(app.BipolarAverageSettingsPanel, 'numeric');
            app.BipolarAverageChannelEditField.ValueChangedFcn = createCallbackFcn(app, @BipolarAverageChannelEditFieldValueChanged, true);
            app.BipolarAverageChannelEditField.Value = 1;
            app.Cutoff1HzEditFieldLabel_3 = uilabel(app.BipolarAverageSettingsPanel);
            app.Cutoff1HzEditFieldLabel_3.Text = 'Cutoff 1 (Hz)';
            app.BipolarAverageCutoff1EditField = uieditfield(app.BipolarAverageSettingsPanel, 'numeric');
            app.BipolarAverageCutoff1EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.BipolarAverageCutoff1EditField.Tag = 'fc_low';
            app.BipolarAverageCutoff1EditField.Value = 25;
            app.Cutoff2HzEditFieldLabel_3 = uilabel(app.BipolarAverageSettingsPanel);
            app.Cutoff2HzEditFieldLabel_3.Text = 'Cutoff 2 (Hz)';
            app.BipolarAverageCutoff2EditField = uieditfield(app.BipolarAverageSettingsPanel, 'numeric');
            app.BipolarAverageCutoff2EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.BipolarAverageCutoff2EditField.Tag = 'fc_high';
            app.BipolarAverageCutoff2EditField.Value = 400;
            app.StartDatamsSpinner_2Label = uilabel(app.BipolarAverageSettingsPanel);
            app.StartDatamsSpinner_2Label.Text = 'Start Data (ms)';
            app.StartBipolarAverageSpinner = uispinner(app.BipolarAverageSettingsPanel);
            app.StartBipolarAverageSpinner.Step = 5;
            app.StartBipolarAverageSpinner.Limits = [-250 0];
            app.StartBipolarAverageSpinner.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.StartBipolarAverageSpinner.Value = -20;
            app.StopDatamsSpinner_2Label = uilabel(app.BipolarAverageSettingsPanel);
            app.StopDatamsSpinner_2Label.Text = 'Stop Data (ms)';
            app.StopBipolarAverageSpinner = uispinner(app.BipolarAverageSettingsPanel);
            app.StopBipolarAverageSpinner.Step = 5;
            app.StopBipolarAverageSpinner.Limits = [0 250];
            app.StopBipolarAverageSpinner.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.StopBipolarAverageSpinner.Value = 40;
            app.ResetBipolarAverageButton = uibutton(app.BipolarAverageSettingsPanel, 'push');
            app.ResetBipolarAverageButton.ButtonPushedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.ResetBipolarAverageButton.Text = 'Reset';
            app.NEditFieldLabel_2 = uilabel(app.BipolarAverageSettingsPanel);
            app.NEditFieldLabel_2.Text = 'N';
            app.BipolarAverageNEditField = uieditfield(app.BipolarAverageSettingsPanel, 'numeric');
            app.BipolarAverageNEditField.Limits = [1 100];
            app.BipolarAverageNEditField.RoundFractionalValues = 'on';
            app.BipolarAverageNEditField.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.BipolarAverageNEditField.Value = 30;
            app.BipolarAverageRectifyCheckBox = uicheckbox(app.BipolarAverageSettingsPanel);
            app.BipolarAverageRectifyCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterParameterValueChanged, true);
            app.BipolarAverageRectifyCheckBox.Text = 'Rectify';
            app.BipolarAverageRectifyCheckBox.Value = true;
            app.RMSContourTab = uitab(app.TabGroup);
            app.RMSContourTab.Title = 'RMS Contour';
            app.RMSContourTab.Tag = 'RC';
            app.RMSContourGridLayout = uigridlayout(app.RMSContourTab);
            app.RMSContourSettingsPanel = uipanel(app.RMSContourGridLayout);
            app.RMSContourSettingsPanel.Title = 'RMS Contour Settings';
            app.ICAStreamTab = uitab(app.TabGroup);
            app.ICAStreamTab.Title = 'ICA Stream';
            app.ICAStreamTab.Tag = 'IS';
            app.ICAStreamGridLayout = uigridlayout(app.ICAStreamTab);
            app.ICAUIAxes = uiaxes(app.ICAStreamGridLayout);
            title(app.ICAUIAxes, 'ICA Streams')
            xlabel(app.ICAUIAxes, 'Time (s)')
            ylabel(app.ICAUIAxes, 'Amplitude (a.u.)')
            zlabel(app.ICAUIAxes, 'Z')
            app.ICAStreamChannelsPanel = uipanel(app.ICAStreamGridLayout);
            app.ICAStreamChannelsPanel.Title = 'ICA Stream Channels';
            app.ICA01SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA01SwitchLabel.Text = 'ICA01';
            app.ICA01Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA01Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA01Switch.Tag = '1';
            app.ICA02Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA02Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA02Switch.Tag = '2';
            app.ICA02SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA02SwitchLabel.Text = 'ICA02';
            app.ICA03Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA03Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA03Switch.Tag = '3';
            app.ICA03SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA03SwitchLabel.Text = 'ICA03';
            app.ICA04Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA04Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA04Switch.Tag = '4';
            app.ICA04SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA04SwitchLabel.Tag = '4';
            app.ICA04SwitchLabel.Text = 'ICA04';
            app.ICA05SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA05SwitchLabel.Text = 'ICA05';
            app.ICA05Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA05Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA05Switch.Tag = '5';
            app.ICA06Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA06Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA06Switch.Tag = '6';
            app.ICA06SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA06SwitchLabel.Text = 'ICA06';
            app.ICA07Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA07Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA07Switch.Tag = '7';
            app.ICA07SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA07SwitchLabel.Text = 'ICA07';
            app.ICA08Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA08Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA08Switch.Tag = '8';
            app.ICA08SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA08SwitchLabel.Tag = '4';
            app.ICA08SwitchLabel.Text = 'ICA08';
            app.ICA09SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA09SwitchLabel.Text = 'ICA09';
            app.ICA09Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA09Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA09Switch.Tag = '9';
            app.ICA10Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA10Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA10Switch.Tag = '10';
            app.ICA10SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA10SwitchLabel.Text = 'ICA10';
            app.ICA11Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA11Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA11Switch.Tag = '11';
            app.ICA11SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA11SwitchLabel.Text = 'ICA11';
            app.ICA12Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA12Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA12Switch.Tag = '12';
            app.ICA12SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA12SwitchLabel.Tag = '4';
            app.ICA12SwitchLabel.Text = 'ICA12';
            app.ICA13SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA13SwitchLabel.Text = 'ICA13';
            app.ICA13Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA13Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA13Switch.Tag = '13';
            app.ICA14Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA14Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA14Switch.Tag = '14';
            app.ICA14SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA14SwitchLabel.Text = 'ICA14';
            app.ICA15Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA15Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA15Switch.Tag = '15';
            app.ICA15SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA15SwitchLabel.Text = 'ICA15';
            app.ICA16Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA16Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA16Switch.Tag = '16';
            app.ICA16SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA16SwitchLabel.Tag = '4';
            app.ICA16SwitchLabel.Text = 'ICA16';
            app.ICA17SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA17SwitchLabel.Text = 'ICA17';
            app.ICA17Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA17Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA17Switch.Tag = '17';
            app.ICA18Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA18Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA18Switch.Tag = '18';
            app.ICA18SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA18SwitchLabel.Text = 'ICA18';
            app.ICA19Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA19Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA19Switch.Tag = '19';
            app.ICA19SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA19SwitchLabel.Text = 'ICA19';
            app.ICA20Switch = uiswitch(app.ICAStreamChannelsPanel, 'slider');
            app.ICA20Switch.ValueChangedFcn = createCallbackFcn(app, @ICASwitchValueChanged, true);
            app.ICA20Switch.Tag = '20';
            app.ICA20SwitchLabel = uilabel(app.ICAStreamChannelsPanel);
            app.ICA20SwitchLabel.Tag = '4';
            app.ICA20SwitchLabel.Text = 'ICA20';
            app.ToggleAllBipolarChannelsSwitchLabel_2 = uilabel(app.ICAStreamChannelsPanel);
            app.ToggleAllBipolarChannelsSwitchLabel_2.Text = 'All Channels';
            app.ToggleAllICAChannelsSwitch = uiswitch(app.ICAStreamChannelsPanel, 'toggle');
            app.ToggleAllICAChannelsSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleAllICAChannelsSwitchValueChanged, true);
            app.ICARasterTab = uitab(app.TabGroup);
            app.ICARasterTab.Title = 'ICA Raster';
            app.ICARasterTab.Tag = 'IR';
            app.ICARasterGridLayout = uigridlayout(app.ICARasterTab);
            app.ICARasterSettingsPanel = uipanel(app.ICARasterGridLayout);
            app.ICARasterSettingsPanel.Title = 'ICA Raster Settings';
            app.ParametersConnectionStatusLampLabel = uilabel(app.SAGADataViewerUIFigure);
            app.ParametersConnectionStatusLampLabel.Text = 'Parameters Connection Status';
            app.ParametersConnectionStatusLamp = uilamp(app.SAGADataViewerUIFigure);
            app.SAGALabel = uilabel(app.SAGADataViewerUIFigure);
            app.SAGALabel.Text = 'SAGA';
            app.DateTimeLabel = uilabel(app.SAGADataViewerUIFigure);
            app.DataConnectionStatusLampLabel = uilabel(app.SAGADataViewerUIFigure);
            app.DataConnectionStatusLampLabel.Text = 'Data Connection Status';
            app.DataConnectionStatusLamp = uilamp(app.SAGADataViewerUIFigure);
            app.TriggerSyncBitEditFieldLabel = uilabel(app.SAGADataViewerUIFigure);
            app.TriggerSyncBitEditFieldLabel.Text = '(Trigger) Sync Bit';
            app.TriggerSyncBitEditField = uieditfield(app.SAGADataViewerUIFigure, 'numeric');
            app.TriggerSyncBitEditField.Limits = [0 16];
            app.TriggerSyncBitEditField.RoundFractionalValues = 'on';
            app.TriggerSyncBitEditField.Value = 9;
            % Show the figure after all components are created
            app.SAGADataViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAGA_Data_Visualizer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAGADataViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAGADataViewerUIFigure)
        end
    end
end