classdef TMSi_2Device_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TMSiInterfaceUIFigure        matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        SerialMonitorPushTool        matlab.ui.container.toolbar.PushTool
        GraphPushTool                matlab.ui.container.toolbar.PushTool
        TMSiSAGARecordingPanel       matlab.ui.container.Panel
        ImpedancesButton             matlab.ui.control.Button
        RecordButton                 matlab.ui.control.Button
        StreamButton                 matlab.ui.control.Button
        TMSiSAGAConnectionInformationPanel  matlab.ui.container.Panel
        DeviceBSerialEditField       matlab.ui.control.NumericEditField
        DeviceBSerialEditFieldLabel  matlab.ui.control.Label
        DeviceASerialEditField       matlab.ui.control.NumericEditField
        DeviceASerialEditFieldLabel  matlab.ui.control.Label
        TMSiConnectionStatusImage    matlab.ui.control.Image
        ConnectButton                matlab.ui.control.StateButton
        SessionInformationPanel      matlab.ui.container.Panel
        TankBrowserButton            matlab.ui.control.Button
        TankEditField                matlab.ui.control.EditField
        TankEditFieldLabel           matlab.ui.control.Label
        SubjectEditField             matlab.ui.control.EditField
        SubjectEditFieldLabel        matlab.ui.control.Label
        DatePicker                   matlab.ui.control.DatePicker
        DateDatePickerLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        SAGA_Interface                                   % Wrapper class for TMSiSAGA devices
        Serial_Interface         = []                    % Microcontroller interface class from SerialMonitor
        Data          DataBuffer = DataBuffer([2, 1]);   % For storing application data samples. 
        Monitor                                          % SerialMonitor application for stims and parameter sets.
        Listeners           cell = cell(5, 1);           % Event listeners to monitor different events
        Graph                                            % UIFigure with grid layout for triggered averages
    end
    
    properties (Access = protected)
        hasMonitor = true; % Is the SerialMonitor app open?
        hasGraph = false;  % Is the Graph open?
        isRecording = false; % Are we recording on the SAGA_Interface?
        isStreaming = false; % Are we streaming (but not recording) from SAGA_Interface?
        connection = struct('COM', false, 'SAGA', false); % Status of each connection
        tankPath  % Where is the base folder that we save files?
    end
    
    properties (Constant, Access = protected)
        DEFAULT_TANK_BASE = 'R:\NMLShare\raw_data\primate';
        DEFAULT_SUBJECT = 'Frank';
        TAGS = ["A", "B"];
    end
    
    methods (Access = public)
        % Callback for when a new Connection is opened or closed on SerialMonitor application.
        function HandleConnectionEvent(app, src, evt)
            switch evt.type
                case "Connect"
                    app.Serial_Interface = src.Interface;
                    app.connection.COM = true;
                    if app.connection.SAGA
                        app.Listeners{4} = addlistener(app.SAGA_Interface, "DiskFileEvent", @(~, evt)app.HandleDiskFileEvent(evt));
                    end
                    app.ConnectButton.Enable = 'on';
                case "Disconnect"
                    app.Serial_Interface = [];
                    app.connection.COM = false;
                    try
                        delete(app.Listeners{4});
                    catch
                    end
                    app.ConnectButton.Enable = 'off';
                otherwise
                    error('%s connection event not handled.', evt.type);
            end
        end
        
        % Callback for when we start or stop a recording
        function HandleDiskFileEvent(app, evt)
            switch evt.type
                case "Start"
                    % Then indicate we are ready for stim train via Serial
                    % interface.
                    app.Serial_Interface.readyForStims();
                case "Stop"
                    % Then indicate we are ready for new parameters via
                    % Serial interface.
                    app.Serial_Interface.readyForParams();
                otherwise
                    error("I have not yet implemented handling for `%s` DiskFileEventData type.", evt.type);
            end
        end
        
        % Callback for when the Graph figure is closed.
        function HandleGraphClosing(app)
            app.hasGraph = false;
            app.GraphPushTool.Enable = 'on';
            app.Graph = [];
        end
        
        % Callback for when the SerialMonitor figure is closed.
        function HandleMonitorClosing(app)
            app.hasMonitor = false;
            app.SerialMonitorPushTool.Enable = 'on';
            app.Monitor = [];
            app.SAGA_Interface = [];
            try
                delete(app.Listeners{4});
            catch                
            end
            app.connection.COM = false;
        end
        
        % Callback to handle status change events from SAGA_Interface monitor running on background worker.
        function HandleSamplingEvent(app, evt)
            iArray = app.TAGS == evt.array;
            app.Data(iArray).merge(evt.data);
            if app.hasGraph
                app.Graph(iArray).pushData(app.Data(iArray));
            else
                fprintf(1, '%d: %.2f\n', [app.Data.index(1:10); app.Data.samples(1, 1:10)]);
                
            end
        end
    end
    
    methods (Access = protected)
        % Updates the tank path based on Subject & Date
        function UpdateTankPath(app)
            value = app.DatePicker.Value;
            app.tankPath = fullfile(app.DEFAULT_TANK_BASE, app.SubjectEditField.Value, sprintf('%s_%d_%02d_%02d', app.SubjectEditField.Value, year(value), month(value), day(value)));
            if exist(app.tankPath, 'dir')==0
                mkdir(app.tankPath);
            end
            app.TankEditField.Value = app.tankPath;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('C:\Program Files\TMSi\TMSi Polybench');
            app.Monitor = SerialMonitor;
            app.DatePicker.Value = datetime(date);
            app.Listeners{1} = addlistener(app.Monitor.TMSiRippleSerialMonitorUIFigure, "ObjectBeingDestroyed", @(~,~)app.HandleMonitorClosing);
            app.Listeners{2} = addlistener(app.Monitor, "ConnectionEvent", @app.HandleConnectionEvent);
            app.SubjectEditField.Value = app.DEFAULT_SUBJECT;
            app.tankPath = fullfile(app.DEFAULT_TANK_BASE, app.DEFAULT_SUBJECT, sprintf('%s_%d_%02d_%02d', app.DEFAULT_SUBJECT, year(today), month(today), day(today)));
            if exist(app.tankPath, 'dir')==0
                mkdir(app.tankPath);
            end
            app.TankEditField.Value = app.tankPath;
            
            app.SAGA_Interface = TMSiSAGA_Handler();
            
        end

        % Close request function: TMSiInterfaceUIFigure
        function TMSiInterfaceUIFigureCloseRequest(app, event)
            if app.hasMonitor
                close(app.Monitor.TMSiRippleSerialMonitorUIFigure);
            end
            if app.hasGraph
                close(app.Graph);
            end
            for ii = 1:numel(app.Listeners)
                try
                    delete(app.Listeners{ii});
                catch
                end
            end
            delete(app);
        end

        % Clicked callback: SerialMonitorPushTool
        function SerialMonitorPushToolClicked(app, event)
            app.Monitor = SerialMonitor;
            app.hasMonitor = true;
            app.SerialMonitorPushTool.Enable = 'off';
            try
                delete(app.Listeners{1});
            catch
            end
            app.Listeners{1} = addlistener(app.Monitor.TMSiRippleSerialMonitorUIFigure, "ObjectBeingDestroyed", @(~,~)app.HandleMonitorClosing);
            try
                delete(app.Listeners{2});
            catch
            end
            app.Listeners{2} = addlistener(app.Monitor, "ConnectionEvent", @(src, evt)app.HandleConnectionEvent);
        end

        % Button pushed function: TankBrowserButton
        function TankBrowserButtonPushed(app, event)
            folder = uigetdir(app.tankPath, 'Select Data Tank Folder');
            if folder ~= 0
                app.tankPath = folder;
                app.TankEditField.Value = folder;
            end
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
            app.UpdateTankPath();
        end

        % Value changed function: SubjectEditField
        function SubjectEditFieldValueChanged(app, event)
            app.UpdateTankPath();
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            if value
                app.TMSiInterfaceUIFigure.Pointer = 'watch';
                drawnow;
                app.SAGA_Interface.setFileInfo(app.SubjectEditField.Value, app.TankEditField.Value);
                app.SAGA_Interface.begin(app.DeviceASerialEditField.Value, app.DeviceBSerialEditField.Value);
                app.TMSiConnectionStatusImage.ImageSource = 'baseline_link_black_24dp.png';
                app.ConnectButton.Text = 'Disconnect';
                app.RecordButton.Enable = 'on';
                app.StreamButton.Enable = 'on';
                app.ImpedancesButton.Enable = 'on';
                app.connection.SAGA = true;
                if app.connection.COM
                    app.Listeners{4} = addlistener(app.SAGA_Interface, "DiskFileEvent", @(~, evt)app.HandleDiskFileEvent(evt));
                end
                app.Listeners{5} = addlistener(app.SAGA_Interface, "SamplingEvent", @(~, evt)app.HandleSamplingEvent(evt));
                app.TMSiInterfaceUIFigure.Pointer = 'arrow';
            else
                app.TMSiInterfaceUIFigure.Pointer = 'watch';
                app.RecordButton.Enable = 'off';
                app.StreamButton.Enable = 'off';
                app.ImpedancesButton.Enable = 'off';
                drawnow;
                app.SAGA_Interface.shutdown();
                app.TMSiConnectionStatusImage.ImageSource = 'baseline_link_off_black_24dp.png';
                app.ConnectButton.Text = 'Connect';
                app.connection.SAGA = false;
                try
                    delete(app.Listeners{4});
                catch
                end
                try
                    delete(app.Listeners{5});
                catch
                end
                app.TMSiInterfaceUIFigure.Pointer = 'arrow';
            end
        end

        % Clicked callback: GraphPushTool
        function GraphPushToolClicked(app, event)
            try
                delete(app.Listeners{3});
            catch
            end
            app.Graph = matlab.ui.Figure(...
                'Name', 'HD-EMG Real-Time Display', ...
                'Units', 'Normalized', ...
                'Position', [0.1 0.1 0.8 0.8], ...
                'Color', 'w');
            app.hasGraph = true;
            app.GraphPushTool.Enable = 'off';
            app.Listeners{3} = addlistener(app.Graph, "ObjectBeingDestroyed", @(~,~)app.HandleGraphClosing);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            if app.isRecording
                app.TMSiInterfaceUIFigure.Pointer = 'watch';
                drawnow;
                app.RecordButton.Icon = 'baseline_radio_button_checked_black_24dp.png';
                app.RecordButton.Text = "Record";
                app.isRecording = false;
                app.ConnectButton.Enable = 'on';
                app.StreamButton.Enable = 'on';
                app.ImpedancesButton.Enable = 'on';
                app.SAGA_Interface.stop();
                app.TMSiInterfaceUIFigure.Pointer = 'arrow';
            else
                app.TMSiInterfaceUIFigure.Pointer = 'watch';
                app.ConnectButton.Enable = 'off';
                app.StreamButton.Enable = 'off';
                app.ImpedancesButton.Enable = 'off';
                app.SAGA_Interface.ready();
                drawnow;
                app.RecordButton.Text = "Stop";
                app.RecordButton.Icon = 'baseline_stop_black_24dp.png';
                app.isRecording = true;
                app.SAGA_Interface.record();
                app.TMSiInterfaceUIFigure.Pointer = 'arrow';
            end
        end

        % Button pushed function: StreamButton
        function StreamButtonPushed(app, event)
            if app.isStreaming
                app.TMSiInterfaceUIFigure.Pointer = 'watch';
                drawnow;
                app.StreamButton.Icon = 'outline_live_tv_black_24dp.png';
                app.StreamButton.Text = "Stream";
                app.isStreaming = false;
                app.ConnectButton.Enable = 'on';
                app.RecordButton.Enable = 'on';
                app.ImpedancesButton.Enable = 'on';
                app.TMSiInterfaceUIFigure.Pointer = 'arrow';
            else
                app.TMSiInterfaceUIFigure.Pointer = 'watch';
                app.ConnectButton.Enable = 'off';
                app.RecordButton.Enable = 'off';
                app.ImpedancesButton.Enable = 'off';
                app.SAGA_Interface.ready();
                drawnow;
                app.StreamButton.Icon = 'baseline_cancel_black_24dp.png';
                app.StreamButton.Text = "Stop";
                app.isStreaming = true;
                app.TMSiInterfaceUIFigure.Pointer = 'arrow';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TMSiInterfaceUIFigure = uifigure('Visible', 'off');
            app.TMSiInterfaceUIFigure.Name = 'TMSi 2-Device HD-EMG Interface';
            app.TMSiInterfaceUIFigure.Icon = 'cmu_tartans_logo.jpg';
            app.TMSiInterfaceUIFigure.CloseRequestFcn = createCallbackFcn(app, @TMSiInterfaceUIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.TMSiInterfaceUIFigure);
            app.SerialMonitorPushTool = uipushtool(app.Toolbar);
            app.SerialMonitorPushTool.Tooltip = {'Start the Serial Parameters/Stims Monitor.'};
            app.SerialMonitorPushTool.ClickedCallback = createCallbackFcn(app, @SerialMonitorPushToolClicked, true);
            app.SerialMonitorPushTool.Enable = 'off';
            app.SerialMonitorPushTool.Icon = 'outline_desktop_windows_black_24dp.png';
            app.GraphPushTool = uipushtool(app.Toolbar);
            app.GraphPushTool.Tooltip = {'Open stim-triggered average interface.'};
            app.GraphPushTool.ClickedCallback = createCallbackFcn(app, @GraphPushToolClicked, true);
            app.GraphPushTool.Icon = 'outline_flash_on_black_24dp.png';
            app.GraphPushTool.Separator = 'on';
            app.SessionInformationPanel = uipanel(app.TMSiInterfaceUIFigure);
            app.SessionInformationPanel.Title = 'Session Information';
            app.DateDatePickerLabel = uilabel(app.SessionInformationPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.DatePicker = uidatepicker(app.SessionInformationPanel);
            app.DatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker.Value = datetime([2021 12 2]);
            app.SubjectEditFieldLabel = uilabel(app.SessionInformationPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.SessionInformationPanel, 'text');
            app.SubjectEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectEditFieldValueChanged, true);
            app.SubjectEditField.Placeholder = 'Subject';
            app.SubjectEditField.Value = 'Frank';
            app.TankEditFieldLabel = uilabel(app.SessionInformationPanel);
            app.TankEditFieldLabel.Text = 'Tank';
            app.TankEditField = uieditfield(app.SessionInformationPanel, 'text');
            app.TankEditField.Placeholder = 'Tank';
            app.TankEditField.Value = 'R:\NMLShare\raw_data\primate\Frank';
            app.TankBrowserButton = uibutton(app.SessionInformationPanel, 'push');
            app.TankBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @TankBrowserButtonPushed, true);
            app.TankBrowserButton.Icon = 'baseline_folder_black_24dp.png';
            app.TankBrowserButton.Tooltip = {'Browse for data Tank.'};
            app.TankBrowserButton.Text = '';
            app.TMSiSAGAConnectionInformationPanel = uipanel(app.TMSiInterfaceUIFigure);
            app.TMSiSAGAConnectionInformationPanel.Title = 'TMSiSAGA Connection Information';
            app.ConnectButton = uibutton(app.TMSiSAGAConnectionInformationPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Tooltip = {'Connect to TMSiSAGA devices (must connect to COM first).'};
            app.ConnectButton.Text = 'Connect';
            app.TMSiConnectionStatusImage = uiimage(app.TMSiSAGAConnectionInformationPanel);
            app.TMSiConnectionStatusImage.ImageSource = 'baseline_link_off_black_24dp.png';
            app.DeviceASerialEditFieldLabel = uilabel(app.TMSiSAGAConnectionInformationPanel);
            app.DeviceASerialEditFieldLabel.Text = 'Device A Serial';
            app.DeviceASerialEditField = uieditfield(app.TMSiSAGAConnectionInformationPanel, 'numeric');
            app.DeviceASerialEditField.ValueDisplayFormat = '%10d';
            app.DeviceASerialEditField.Value = 1005210029;
            app.DeviceBSerialEditFieldLabel = uilabel(app.TMSiSAGAConnectionInformationPanel);
            app.DeviceBSerialEditFieldLabel.Text = 'Device B Serial';
            app.DeviceBSerialEditField = uieditfield(app.TMSiSAGAConnectionInformationPanel, 'numeric');
            app.DeviceBSerialEditField.ValueDisplayFormat = '%10d';
            app.DeviceBSerialEditField.Value = 1005210028;
            app.TMSiSAGARecordingPanel = uipanel(app.TMSiInterfaceUIFigure);
            app.TMSiSAGARecordingPanel.Title = 'TMSiSAGA Stream Options';
            app.StreamButton = uibutton(app.TMSiSAGARecordingPanel, 'push');
            app.StreamButton.ButtonPushedFcn = createCallbackFcn(app, @StreamButtonPushed, true);
            app.StreamButton.Icon = 'outline_live_tv_black_24dp.png';
            app.StreamButton.Enable = 'off';
            app.StreamButton.Text = 'Stream';
            app.RecordButton = uibutton(app.TMSiSAGARecordingPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Icon = 'baseline_radio_button_checked_black_24dp.png';
            app.RecordButton.Enable = 'off';
            app.RecordButton.Text = 'Record';
            app.ImpedancesButton = uibutton(app.TMSiSAGARecordingPanel, 'push');
            app.ImpedancesButton.Icon = 'baseline_assessment_black_24dp.png';
            app.ImpedancesButton.Enable = 'off';
            app.ImpedancesButton.Text = 'Impedances';
            % Show the figure after all components are created
            app.TMSiInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TMSi_2Device_Interface

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TMSiInterfaceUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TMSiInterfaceUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TMSiInterfaceUIFigure)
        end
    end
end