classdef TMSi_Client < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TMSiClientUIFigure           matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        NHPTask2SAGAControllerLabel  matlab.ui.control.Label
        TMSiStateButtonGroup         matlab.ui.container.ButtonGroup
        SAGABParametersLamp          matlab.ui.control.Lamp
        SAGABParametersLampLabel     matlab.ui.control.Label
        SAGAAParametersLamp          matlab.ui.control.Lamp
        SAGAAParametersLampLabel     matlab.ui.control.Label
        ImpedanceButton              matlab.ui.control.ToggleButton
        QuitButton                   matlab.ui.control.ToggleButton
        RecordButton                 matlab.ui.control.ToggleButton
        RunButton                    matlab.ui.control.ToggleButton
        IdleButton                   matlab.ui.control.ToggleButton
        StartDatePickerLabel         matlab.ui.control.Label
        StartDatePicker              matlab.ui.control.DatePicker
        UpdateNameButton             matlab.ui.control.Button
        BlockSpinner                 matlab.ui.control.Spinner
        BlockSpinnerLabel            matlab.ui.control.Label
        SubjectEditField             matlab.ui.control.EditField
        SubjectEditFieldLabel        matlab.ui.control.Label
        TMSiControlPortSpinner       matlab.ui.control.Spinner
        TMSiControlPortSpinnerLabel  matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        ConnectButton                matlab.ui.control.StateButton
        ServerIPEditField            matlab.ui.control.EditField
        ServerIPEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        connected = false % Is the client currently connected to the TCP server?
    end
    
    properties (Hidden, Access = public)
        client    % TCPclient connected to server
        client_listener % Client listener handle
        pclient   % TCPclient connected to parameters for online visualization
        task_port = udpport("byte", "LocalPort", 3033, "EnablePortSharing", true) % UDPPort listening to task messages (Potentially)
        Parent
    end

    properties (SetAccess = protected, GetAccess = public)
        STREAM_HOST_IP = "127.0.0.1";
        UDP_STATE_BROADCAST_PORT = 3030;
        UDP_NAME_BROADCAST_PORT = 3031;
    end
    
    methods (Access = public)
        function cleanUpHandles(app)
            try %#ok<*TRYNC> 
                delete(app.client);
            end

            try
                delete(app.client_listener);
            end

            try
                for ii = 1:numel(app.pclient)
                    delete(app.pclient);
                end
            end
        end
        
        function relayParameterConfiguration(app, src, evt)
            %RELAYPARAMETERCONFIGURATION Updates the configuration so that the correct number of bytes are read in the callback config
            data = readline(src);
            fprintf(1,'[TMSI CLIENT] Received message (pclient): %s\n', data);
            msg = sprintf('vis.%s', data);
            app.client.writeline(msg);
            fprintf(1,'[TMSI CLIENT] Relayed message (pclient): %s\n', msg);
        end
        
        function task__read_data_cb(app, src, ~)
            %TASK__READ_DATA_CB  Callback when line terminator for udp port is detected.

            data = string(readline(src));
            route = strsplit(data, '.');
            fprintf(1,'[TMSI CLIENT] Received Message (client): %s\n', data);
            switch lower(route(1))
                case "t" % task state data
                    switch lower(route(2))
                        case "r" % recording state
                            app.TMSiStateButtonGroup.SelectedObject = findobj(app.TMSiStateButtonGroup.Buttons, 'Tag', char(route(3)));
                            if app.TMSiStateButtonGroup.Enable
                                app.TMSiStateButtonGroupSelectionChanged();
                            end
                            if (strcmpi(route(3), "run")) || (strcmpi(route(3), "idle"))
                                app.BlockSpinner.Value = app.BlockSpinner.Value + 1;
                                app.UpdateNameButtonPushed();
                            end
                            fprintf(1,'%s -> TMSi State -> %s\n', string(datetime("now")), route(3));
                        case "s"
                            fprintf(1,'%s -> Task State -> %d\n', string(datetime("now")), str2double(route(3)));
                        otherwise
                            fprintf(1,'<strong>Received unhandled task-message</strong>: %s\n', data);
                    end
                case "p" % parameter data
                    switch lower(route(2))
                        case "n" % name data
                            src.write([char(sprintf("%s_%%s_%d", route(3), app.BlockSpinner.Value)) 10], "string", ...
                                app.STREAM_HOST_IP,  ...
                                app.UDP_NAME_BROADCAST_PORT);
                            app.BlockSpinner.Value = app.BlockSpinner.Value + 1;
                            app.UpdateNameButtonPushed();
                        otherwise
                            fprintf(1,'<strong>Received unhandled parameter-message</strong>: %s\n', data);
                    end

                otherwise
                    fprintf(1,'<strong>Received unhandled message</strong>: %s\n', data);
            end
        end

        function connect(app, IP, PORT)
            try  
                delete(app.client);
            end
            try
                app.client = tcpclient(IP, PORT);
            catch
                fprintf(1,'Unable to connect to TCP Server ( %s:%d )\n', IP, PORT);
                app.Lamp.Color = [0.8 0.4 0.1];
                app.ConnectButton.Text = "(Re-)Connect";
                app.ConnectButton.Value = 0;
                return;
            end
            app.client.UserData = SubjectMetadata();
            app.client_listener = addlistener(app.client.UserData, "MetaEvent", @(src,~)app.update_meta_fields(src));
            app.client.configureCallback("terminator", @callback.clientHandleControllerMessages);
            app.Lamp.Color = [0.1 0.8 0.1];
            app.ConnectButton.Text = "Disconnect";
            app.TMSiStateButtonGroup.Enable = 'on';
            app.UpdateNameButton.Enable = 'on';
            app.connected = true;
            if ~isempty(app.Parent)
                if isvalid(app.Parent)
                    app.Parent.TMSiLamp.Color = [0.0 0.6 0.0];
                end
            end
        end
        
        function disconnect(app)
            try 
                delete(app.client);
            end
            try
                delete(app.client_listener);
            end
            app.Lamp.Color = [0.1 0.1 0.8];
            app.ConnectButton.Text = "Connect";
            app.TMSiStateButtonGroup.Enable = 'off';
            app.UpdateNameButton.Enable = 'off';
            app.connected = false;
            if ~isempty(app.Parent)
                if isvalid(app.Parent)
                    app.Parent.TMSiLamp.Color = [0.1 0.1 0.8];
                end
            end
        end

        function update_meta_fields(app, src)
            app.SubjectEditField.Value = src.SUBJ;
            app.StartDatePicker.Value = src.Date;
            app.BlockSpinner.Value = src.BLOCK;
        end
        
        function update_metadata(app, SUBJ, YYYY, MM, DD, BLOCK)
            %UPDATE_METADATA Update the metadata fields and execute updating callback as well.
            app.SubjectEditField.Value = SUBJ;
            app.StartDatePicker.Value = datetime(YYYY, MM, DD);
            app.BlockSpinner.Value = BLOCK;
            if app.UpdateNameButton.Enable
                callback.handleTMSiRecNameMetadata(app.client, SUBJ, YYYY, MM, DD, BLOCK);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, config)
            app.StartDatePicker.Value = datetime('today', 'Format', 'uuuu-MM-dd');
            app.task_port.configureTerminator("LF");
            app.task_port.configureCallback("terminator", @app.task__read_data_cb);
            app.TMSiClientUIFigure.DeleteFcn = @(~,~)app.cleanUpHandles();
            if nargin > 1
                app.pclient = struct;
                app.STREAM_HOST_IP = config.Server.Address.UDP;
                app.UDP_STATE_BROADCAST_PORT = config.Server.UDP.state;
                app.UDP_NAME_BROADCAST_PORT = config.Server.UDP.name;
                app.ServerIPEditField.Value = config.Server.Address.TCP;
                if config.SAGA.A.Enable
                    app.pclient.A = tcpclient(config.Server.Address.TCP, config.Server.TCP.A.Parameters);
                    app.pclient.A.UserData = struct("tag", "A");
                    configureTerminator(app.pclient.A, "LF");
                    configureCallback(app.pclient.A, "terminator", @(src,evt)app.relayParameterConfiguration(src,evt));
                    app.SAGAAParametersLamp.Color = [0.39,0.83,0.07];
                end
                if config.SAGA.B.Enable
                    app.pclient.B = tcpclient(config.Server.Address.TCP, config.Server.TCP.B.Parameters);
                    app.pclient.B.UserData = struct("tag", "B");
                    configureTerminator(app.pclient.B, "LF");
                    configureCallback(app.pclient.B, "terminator", @(src,evt)app.relayParameterConfiguration(src,evt));
                    app.SAGABParametersLamp.Color = [0.39,0.83,0.07];
                end
            end
        end

        % Close request function: TMSiClientUIFigure
        function TMSiClientUIFigureCloseRequest(app, event)
            try %#ok<TRYNC> 
                delete(app.client);
            end
            delete(app)
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            if value
                app.connect(app.ServerIPEditField.Value, app.TMSiControlPortSpinner.Value);
            else
                app.disconnect();
            end
        end

        % Button pushed function: UpdateNameButton
        function UpdateNameButtonPushed(app, event)
            t = app.StartDatePicker.Value;
            YYYY = year(t);
            MM = month(t);
            DD = day(t);
            callback.handleTMSiRecNameMetadata(app.client, app.SubjectEditField.Value, YYYY, MM, DD, app.BlockSpinner.Value);
        end

        % Selection changed function: TMSiStateButtonGroup
        function TMSiStateButtonGroupSelectionChanged(app, event)
            selectedButton = app.TMSiStateButtonGroup.SelectedObject;
            switch selectedButton.Tag
                case "rec"
                    app.Lamp.Color = [0.2 1.0 0.2];
                case "idle"
                    app.Lamp.Color = [0.0 0.6 0.0];
                case "run"
                    app.Lamp.Color = [0.3 0.8 0.3];
                case "imp"
                    app.Lamp.Color = [0.2 0.8 0.8];
                case "quit"
                    app.Lamp.Color = [0.8 0.5 0.1];
            end
            callback.handleSetTMSiState(app.client, selectedButton.Tag);
            if ~isempty(app.Parent)
                if isvalid(app.Parent)
                    app.Parent.TMSiLamp.Color = app.Lamp.Color;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TMSiClientUIFigure = uifigure('Visible', 'off');
            app.TMSiClientUIFigure.Name = 'TMSi Client';
            app.TMSiClientUIFigure.Icon = 'cmu_tartans_logo.jpg';
            app.TMSiClientUIFigure.CloseRequestFcn = createCallbackFcn(app, @TMSiClientUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.TMSiClientUIFigure);
            app.ServerIPEditFieldLabel = uilabel(app.GridLayout);
            app.ServerIPEditFieldLabel.Text = 'Server IP';
            app.ServerIPEditField = uieditfield(app.GridLayout, 'text');
            app.ServerIPEditField.Value = '127.0.0.1';
            app.ConnectButton = uibutton(app.GridLayout, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp = uilamp(app.GridLayout);
            app.TMSiControlPortSpinnerLabel = uilabel(app.GridLayout);
            app.TMSiControlPortSpinnerLabel.Text = 'TMSi Control Port';
            app.TMSiControlPortSpinner = uispinner(app.GridLayout);
            app.TMSiControlPortSpinner.Limits = [5000 5050];
            app.TMSiControlPortSpinner.RoundFractionalValues = 'on';
            app.TMSiControlPortSpinner.Value = 5000;
            app.SubjectEditFieldLabel = uilabel(app.GridLayout);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.GridLayout, 'text');
            app.SubjectEditField.Value = 'Default';
            app.BlockSpinnerLabel = uilabel(app.GridLayout);
            app.BlockSpinnerLabel.Text = 'Block';
            app.BlockSpinner = uispinner(app.GridLayout);
            app.UpdateNameButton = uibutton(app.GridLayout, 'push');
            app.UpdateNameButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateNameButtonPushed, true);
            app.UpdateNameButton.Icon = 'baseline_save_black_24dp.png';
            app.UpdateNameButton.Enable = 'off';
            app.UpdateNameButton.Text = 'Update Name';
            app.StartDatePicker = uidatepicker(app.GridLayout);
            app.StartDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.StartDatePickerLabel = uilabel(app.GridLayout);
            app.StartDatePickerLabel.Text = 'Start Date';
            app.TMSiStateButtonGroup = uibuttongroup(app.GridLayout);
            app.TMSiStateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TMSiStateButtonGroupSelectionChanged, true);
            app.TMSiStateButtonGroup.Enable = 'off';
            app.TMSiStateButtonGroup.Title = 'TMSi State';
            app.IdleButton = uitogglebutton(app.TMSiStateButtonGroup);
            app.IdleButton.Tag = 'idle';
            app.IdleButton.Icon = 'baseline_stop_black_24dp.png';
            app.IdleButton.Text = 'Idle';
            app.IdleButton.Value = true;
            app.RunButton = uitogglebutton(app.TMSiStateButtonGroup);
            app.RunButton.Tag = 'run';
            app.RunButton.Icon = 'outline_live_tv_black_24dp.png';
            app.RunButton.Text = 'Run';
            app.RecordButton = uitogglebutton(app.TMSiStateButtonGroup);
            app.RecordButton.Tag = 'rec';
            app.RecordButton.Icon = 'baseline_radio_button_checked_black_24dp.png';
            app.RecordButton.Text = 'Record';
            app.QuitButton = uitogglebutton(app.TMSiStateButtonGroup);
            app.QuitButton.Tag = 'quit';
            app.QuitButton.Icon = 'baseline_power_off_black_24dp.png';
            app.QuitButton.Text = 'Quit';
            app.ImpedanceButton = uitogglebutton(app.TMSiStateButtonGroup);
            app.ImpedanceButton.Tag = 'imp';
            app.ImpedanceButton.Icon = 'Impedance-Symbol.png';
            app.ImpedanceButton.Text = 'Impedance';
            app.SAGAAParametersLampLabel = uilabel(app.TMSiStateButtonGroup);
            app.SAGAAParametersLampLabel.Text = 'SAGA A Parameters';
            app.SAGAAParametersLamp = uilamp(app.TMSiStateButtonGroup);
            app.SAGABParametersLampLabel = uilabel(app.TMSiStateButtonGroup);
            app.SAGABParametersLampLabel.Text = 'SAGA B Parameters';
            app.SAGABParametersLamp = uilamp(app.TMSiStateButtonGroup);
            app.NHPTask2SAGAControllerLabel = uilabel(app.GridLayout);
            app.NHPTask2SAGAControllerLabel.Text = 'NHP Task 2SAGA Controller';
            % Show the figure after all components are created
            app.TMSiClientUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TMSi_Client(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TMSiClientUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TMSiClientUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TMSiClientUIFigure)
        end
    end
end